classdef SIMULATOR_TURBOJET_TT3P < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        Switch_2                        matlab.ui.control.ToggleSwitch
        tau_cEditField                  matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        M_2EditField_2                  matlab.ui.control.NumericEditField
        M_2EditField_2Label             matlab.ui.control.Label
        SimulationperformedCheckBox_2   matlab.ui.control.CheckBox
        TurbojetcyclewithidealprecoolerButton  matlab.ui.control.Button
        ComparisonperformedCheckBox_2   matlab.ui.control.CheckBox
        TurbojetcyclewithrealprecoolerButton  matlab.ui.control.Button
        DeltaTnotcooledDropDown_2       matlab.ui.control.DropDown
        DeltaTnotcooledDropDown_2Label  matlab.ui.control.Label
        PressurelossesDropDown_2        matlab.ui.control.DropDown
        PressurelossesLabel_3           matlab.ui.control.Label
        e_preEditField                  matlab.ui.control.NumericEditField
        e_preEditFieldLabel             matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        REALPRECOOLERLabel              matlab.ui.control.Label
        IDEALPRECOOLERLabel             matlab.ui.control.Label
        SimulationPerformedIdealCheckBox_2  matlab.ui.control.CheckBox
        SimulationPerformedIdealCheckBox  matlab.ui.control.CheckBox
        TurbojetcyclewithintercoolerButton  matlab.ui.control.Button
        REALLabel                       matlab.ui.control.Label
        IDEALLabel                      matlab.ui.control.Label
        CYCLELabel                      matlab.ui.control.Label
        SETUPLabel                      matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        ComparisonperformedCheckBox     matlab.ui.control.CheckBox
        TurbojetcyclewithrealintercoolerButton  matlab.ui.control.Button
        REALINTERCOOLERLabel            matlab.ui.control.Label
        IDEALINTERCOOLERLabel           matlab.ui.control.Label
        PRECOOLERLabel                  matlab.ui.control.Label
        PressurelossesDropDown          matlab.ui.control.DropDown
        PressurelossesLabel_2           matlab.ui.control.Label
        INTERCOOLERLabel                matlab.ui.control.Label
        DeltaTnotcooledDropDown         matlab.ui.control.DropDown
        DeltaTnotcooledLabel            matlab.ui.control.Label
        ValuesfoundCheckBox             matlab.ui.control.CheckBox
        OptimizedintercoolingvaluesButton  matlab.ui.control.Button
        TurbojetcyclewithprecoolingButton  matlab.ui.control.Button
        SetidealcycleparametersLabel    matlab.ui.control.Label
        Idealparameters                 matlab.ui.control.Button
        SetrealcycleparametersLabel     matlab.ui.control.Label
        Realparameters                  matlab.ui.control.Button
        DeltaT_preEditField             matlab.ui.control.NumericEditField
        DeltaTPreLabel                  matlab.ui.control.Label
        DeltaT_interEditField           matlab.ui.control.NumericEditField
        DeltaT_interEditFieldLabel      matlab.ui.control.Label
        e_interEditField                matlab.ui.control.NumericEditField
        e_interEditFieldLabel           matlab.ui.control.Label
        SimulationperformedCheckBox     matlab.ui.control.CheckBox
        phiEditField                    matlab.ui.control.NumericEditField
        psiLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        eta_cpEditField                 matlab.ui.control.NumericEditField
        eta_cpLabel                     matlab.ui.control.Label
        eta_tpEditField                 matlab.ui.control.NumericEditField
        eta_tpEditFieldLabel            matlab.ui.control.Label
        PolitropicefficienciesLabel     matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        TurbojetconfigurationLabel      matlab.ui.control.Label
        PressurelossesLabel             matlab.ui.control.Label
        epsilon_nEditField              matlab.ui.control.NumericEditField
        epsilon_nEditFieldLabel         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        epsilon_iEditField              matlab.ui.control.NumericEditField
        epsilon_iEditFieldLabel         matlab.ui.control.Label
        epsilon_bEditField_2            matlab.ui.control.NumericEditField
        epsilon_bEditField_2Label       matlab.ui.control.Label
        Tt_4EditField_3                 matlab.ui.control.NumericEditField
        Tt_4EditField_3Label            matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        KLabel_4                        matlab.ui.control.Label
        TurbojetcyclewithidealintercoolerButton  matlab.ui.control.Button
        KLabel_2                        matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        P_0EditField                    matlab.ui.control.NumericEditField
        DeltaT_cLabel                   matlab.ui.control.Label
        T_0EditField                    matlab.ui.control.NumericEditField
        T_0EditFieldLabel               matlab.ui.control.Label
        M_0EditField                    matlab.ui.control.NumericEditField
        M_0EditFieldLabel               matlab.ui.control.Label
        INTERCOOLINGTab                 matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        IdealTt25analysisTab            matlab.ui.container.Tab
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        OptimizedvaluesinterTab         matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        EditField_32Label               matlab.ui.control.Label
        EditField_26Label               matlab.ui.control.Label
        T_t25Label_2                    matlab.ui.control.Label
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        T_t3Label_2                     matlab.ui.control.Label
        DesignpointTab                  matlab.ui.container.Tab
        W_skJEditField                  matlab.ui.control.NumericEditField
        W_skJEditFieldLabel             matlab.ui.control.Label
        P_t25EditField_2                matlab.ui.control.NumericEditField
        P_t25Label                      matlab.ui.control.Label
        P_t25EditField                  matlab.ui.control.NumericEditField
        P_t25EditFieldLabel             matlab.ui.control.Label
        T_t25EditField_2                matlab.ui.control.NumericEditField
        T_t25Label                      matlab.ui.control.Label
        T_t25EditField                  matlab.ui.control.NumericEditField
        T_t25EditFieldLabel             matlab.ui.control.Label
        INTERCOOLINGPARAMETERSLabel     matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        V_0Label                        matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        T_t4Label                       matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        kPaLabel                        matlab.ui.control.Label
        KLabel_8                        matlab.ui.control.Label
        EfficienciesLabel               matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        V_9Label                        matlab.ui.control.Label
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        pi_9Label                       matlab.ui.control.Label
        P_t9Label                       matlab.ui.control.Label
        T_9Label                        matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        P_t5Label                       matlab.ui.control.Label
        T_t5Label                       matlab.ui.control.Label
        pi_cLabel                       matlab.ui.control.Label
        P_t4Label                       matlab.ui.control.Label
        alpha_bLabel                    matlab.ui.control.Label
        P_t3Label                       matlab.ui.control.Label
        T_t3Label                       matlab.ui.control.Label
        P_t2Label                       matlab.ui.control.Label
        T_t2Label                       matlab.ui.control.Label
        pi_fsLabel                      matlab.ui.control.Label
        P_t0Label                       matlab.ui.control.Label
        T_t0Label                       matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        ThermalLabel                    matlab.ui.control.Label
        PropulsiveLabel                 matlab.ui.control.Label
        OverallLabel                    matlab.ui.control.Label
        DeltaK_ckJLabel                 matlab.ui.control.Label
        C_TSkJgLabel                    matlab.ui.control.Label
        psimsLabel                      matlab.ui.control.Label
        PerformancesLabel               matlab.ui.control.Label
        TrendsTab                       matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        WsanalysisTab                   matlab.ui.container.Tab
        W_sLabel                        matlab.ui.control.Label
        UIAxes14                        matlab.ui.control.UIAxes
        LosseseffectTab                 matlab.ui.container.Tab
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        PRECOOLINGTab                   matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        DesignpointTab_2                matlab.ui.container.Tab
        M_2EditField                    matlab.ui.control.NumericEditField
        M_2EditFieldLabel               matlab.ui.control.Label
        WkJEditField_2                  matlab.ui.control.NumericEditField
        WkJEditField_2Label             matlab.ui.control.Label
        PRECOOLINGPARAMETERLabel        matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        V_0Label_2                      matlab.ui.control.Label
        EditField_58                    matlab.ui.control.NumericEditField
        EditField_57                    matlab.ui.control.NumericEditField
        T_t4Label_2                     matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        kPaLabel_2                      matlab.ui.control.Label
        KLabel_9                        matlab.ui.control.Label
        EfficienciesLabel_2             matlab.ui.control.Label
        EditField_56                    matlab.ui.control.NumericEditField
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.NumericEditField
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        V_9Label_2                      matlab.ui.control.Label
        EditField_50                    matlab.ui.control.NumericEditField
        EditField_49                    matlab.ui.control.NumericEditField
        EditField_48                    matlab.ui.control.NumericEditField
        EditField_47                    matlab.ui.control.NumericEditField
        EditField_46                    matlab.ui.control.NumericEditField
        EditField_45                    matlab.ui.control.NumericEditField
        EditField_44                    matlab.ui.control.NumericEditField
        EditField_43                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_40                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        pi_9Label_2                     matlab.ui.control.Label
        P_t9Label_2                     matlab.ui.control.Label
        T_9Label_2                      matlab.ui.control.Label
        alphaLabel_2                    matlab.ui.control.Label
        P_t5Label_2                     matlab.ui.control.Label
        T_t5Label_2                     matlab.ui.control.Label
        pi_cLabel_2                     matlab.ui.control.Label
        P_t4Label_2                     matlab.ui.control.Label
        alpha_bLabel_2                  matlab.ui.control.Label
        P_t3Label_2                     matlab.ui.control.Label
        T_t3Label_3                     matlab.ui.control.Label
        P_t2Label_2                     matlab.ui.control.Label
        T_t2Label_2                     matlab.ui.control.Label
        pi_fsLabel_2                    matlab.ui.control.Label
        P_t0Label_2                     matlab.ui.control.Label
        T_t0Label_2                     matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_32                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        ThermalLabel_2                  matlab.ui.control.Label
        PropulsiveLabel_2               matlab.ui.control.Label
        OverallLabel_2                  matlab.ui.control.Label
        DeltaK_ckJLabel_2               matlab.ui.control.Label
        C_TSkJgLabel_2                  matlab.ui.control.Label
        psimsLabel_2                    matlab.ui.control.Label
        PerformancesLabel_2             matlab.ui.control.Label
        TrendsT_t3Tab                   matlab.ui.container.Tab
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        TrendsM_2Tab                    matlab.ui.container.Tab
        UIAxes5_3                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        LosseseffectTab_2               matlab.ui.container.Tab
        UIAxes11_2                      matlab.ui.control.UIAxes
        UIAxes10_2                      matlab.ui.control.UIAxes
        UIAxes9_2                       matlab.ui.control.UIAxes
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes6_2                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
       
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TurbojetcyclewithidealintercoolerButton
        function TurbojetcyclewithidealintercoolerButtonPushed(app, event)
            cla(app.UIAxes, 'reset'); % Clear axes control.
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes2_2, 'reset');
            
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            e_interp=0;
            diff_T=0;
            Delta_T_Pc_values=0;
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            
            Tt3_prima_val_opt = app.EditField_32.Value;
            Tt25_val_opt = app.EditField_33.Value;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25,Pt25,Tt25_p,Pt25_p,Tt3_prima,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val_opt,Delta_T_Pc_values,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_val_opt);

       
            %app.UITable.RowName={'Specific Thrust','C_{TS}','\Delta K_{c}'};


            app.EditField_18.Value=specific_thrust;
            app.EditField_19.Value=C_ts*1000000;
            app.EditField_20.Value=Deltak_c/1000;
            app.W_skJEditField.Value = w_s/1000;
            
            %app.UITable_2.RowName={'Overall efficiency', 'Propulsive efficiency','Thermal efficiency'};
            app.EditField_21.Value=overall_eff;
            app.EditField_22.Value=prop_eff;
            app.EditField_23.Value=thermal_eff;
            app.EditField_24.Value=Tt4;
            app.EditField_25.Value=V0;

            app.T_t25EditField.Value=Tt25_val_opt;
            app.T_t25EditField_2.Value=Tt25_p;
            app.P_t25EditField.Value=Pt25/1000;
            app.P_t25EditField_2.Value=Pt25_p/1000;
            
            app.EditField.Value = Tt0;
            app.EditField_2.Value  = Pt0/1000;
            app.EditField_3.Value  = pi_fs;
            app.EditField_4.Value  = Tt2;
            app.EditField_5.Value  = Pt2/1000;
            app.EditField_6.Value  = Tt3_prima_val_opt;
            app.EditField_7.Value  = Pt3_prima/1000;
            app.EditField_8.Value  = pi_c;
            app.EditField_9.Value  = Pt4/1000;
            app.EditField_10.Value  = alpha_b;
            app.EditField_11.Value  = Tt5;
            app.EditField_12.Value  = Pt5/1000;
            app.EditField_13.Value  = alpha_prima;
            app.EditField_14.Value  = T9;
            app.EditField_15.Value  = Pt9/1000;
            app.EditField_16.Value  = pi_9;
            app.EditField_17.Value  = V9;
            M0_values=[M_0-1,M_0];
            Tt4_values=2000:100:2200;
            Tt3_prima_val=Tt3_prima-200:1:Tt3_prima+1450;
            Delta_T_Pc_Values=0;


            specific_thrust_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            
            
            specific_thrust_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));

            
            specific_thrust_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));


            deltak_c=Deltak_c;
            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real(i_1)>0,i_1<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_real(i_1),overall_eff_vec_M0_fixed_real(i_1),C_ts_vec_M0_fixed_real(i_1),prop_eff_vec_M0_fixed_real(i_1),thermal_eff_vec_M0_fixed_real(i_1),deltak_c,W_vec_M0_fixed_real(i_1)] = CALCULATION_IC_TT3P(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_1),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_val_opt);
                i_1=i_1+1;
                
            end


            deltak_c=Deltak_c;
            i_3=1;
            while or(specific_thrust_vec_M0_fixed_theta4down(i_3)>0,i_3<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_theta4down(i_3),overall_eff_vec_M0_fixed_theta4down(i_3),C_ts_vec_M0_fixed_theta4down(i_3),prop_eff_vec_M0_fixed_theta4down(i_3),thermal_eff_vec_M0_fixed_theta4down(i_3),deltak_c,W_vec_M0_fixed_theta4down(i_3)] = CALCULATION_IC_TT3P(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_3),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(1),bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_val_opt);
                i_3=i_3+1;
                
            end
            
            
            if M_0>2
                deltak_c=Deltak_c;
                i_5=1;
                while or(specific_thrust_vec_M0_fixed_theta0down(i_5)>0 ,i_5<length(Tt3_prima_val)) 
                    [specific_thrust_vec_M0_fixed_theta0down(i_5),overall_eff_vec_M0_fixed_theta0down(i_5),C_ts_vec_M0_fixed_theta0down(i_5),prop_eff_vec_M0_fixed_theta0down(i_5),thermal_eff_vec_M0_fixed_theta0down(i_5),deltak_c,W_vec_M0_fixed_theta0down(i_5)] = CALCULATION_IC_TT3P(M0_values(1),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_5),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_val_opt);
                    i_5=i_5+1;
                    
                end
            end

            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;r25=0;r26=0;r27=0;r28=0;
            for i=1:length(prop_eff_vec_M0_fixed_real)
                
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r21==1)
                    if r21==0
                        specific_thrust_vec_M0_fixed_real(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r21=1;
                    else
                        specific_thrust_vec_M0_fixed_real(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r23==1)
                    if r23==0
                        specific_thrust_vec_M0_fixed_theta4down(i)=0;
    %                     W_vec_M0_fixed_theta4down(i)=W_vec_M0_fixed_theta4down(i-1);
                        r23=1;
                    else
                        specific_thrust_vec_M0_fixed_theta4down(i)=NaN;
    %                     W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
            
            
                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r25==1)
                    if r25==0
                        specific_thrust_vec_M0_fixed_theta0down(i)=0;
    %                     W_vec_M0_fixed_theta0down(i)=W_vec_M0_fixed_theta0down(i-1);
                        r25=1;
                    else
                        specific_thrust_vec_M0_fixed_theta0down(i)=NaN;
    %                     W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)<=0,r17==1)
                    if r17==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=0;
                        r17=1;
                    else
                        thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)>=1,r3==1)
                    if r3==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=1;
                        r3=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta0down(i)<=0,r18==1)
                    if r18==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=0;
                        r18=1;
                    else
                        thermal_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
       
                if or(thermal_eff_vec_M0_fixed_theta0down(i)>=1,r5==1)
                    if r5==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=1;
                        r5=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end

                if or(C_ts_vec_M0_fixed_real(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real(i)=NaN;
                    r4=1;
                end
    
                if or(C_ts_vec_M0_fixed_theta4down(i)<=0,r13==0)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                    r13=1;
                end
                
                if Tt3_prima_val(i)>= Tt4_values(1)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                end

                if or(C_ts_vec_M0_fixed_theta0down(i)<=0,r14==0)
                    C_ts_vec_M0_fixed_theta0down(i)=NaN;
                    r14=1;
                end

                if or(prop_eff_vec_M0_fixed_real(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta4down(i)>=1,r15==1)
                    if r15==0
                        prop_eff_vec_M0_fixed_theta4down(i)=1;
                        r15=1;
                    else
                        prop_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta0down(i)>=1,r16==1)
                    if r16==0
                        prop_eff_vec_M0_fixed_theta0down(i)=1;
                        r16=1;
                    else
                        prop_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
    
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real(i)=1;
                        r6=1;
%                     else
%                         overall_eff_vec_M0_fixed_real(i)=1;
                    end
                end
                
                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r19==1)
                    if r19==0
                        overall_eff_vec_M0_fixed_theta4down(i)=0;
                        r19=1;
                    else
                        overall_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta4down(i)>=1,r8==1)
                    if r8==0
                        overall_eff_vec_M0_fixed_theta4down(i)=1;
                        r8=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta4down(i)=1;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r20==1)
                    if r20==0
                        overall_eff_vec_M0_fixed_theta0down(i)=0;
                        r20=1;
                    else
                        overall_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta0down(i)>=1,r9==1)
                    if r9==0
                        overall_eff_vec_M0_fixed_theta0down(i)=1;
                        r9=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end


                if or( W_vec_M0_fixed_real(i)>=0,r26==1)
                    if r26==0
                        W_vec_M0_fixed_real(i)=0;
                        r26=1;
                    else
                        W_vec_M0_fixed_real(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta4down(i)>=0,r27==1)
                    if r27==0
                        W_vec_M0_fixed_theta4down(i)=0;
                        r27=1;
                    else
                        W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta0down(i)>=0,r28==1)
                    if r28==0
                        W_vec_M0_fixed_theta0down(i)=0;
                        r28=1;
                    else
                        W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
                
            end


            [maximo_y, indice_maximo] = max(specific_thrust_vec_M0_fixed_real);
            x_maximo = Tt3_prima_val(indice_maximo);

            [ymax4down, indice_4down] = max(specific_thrust_vec_M0_fixed_theta4down);
            xmax4down = Tt3_prima_val(indice_4down);

            [ymax0down, indice_0down] = max(specific_thrust_vec_M0_fixed_theta0down);
            xmax0down = Tt3_prima_val(indice_0down);
            plot(app.UIAxes,Tt3_prima_val,specific_thrust_vec_M0_fixed_real,'k-',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes,'on');
            plot(app.UIAxes,x_maximo,maximo_y,'ko','MarkerSize',5);

            plot(app.UIAxes,xmax4down,ymax4down,'ro','MarkerSize',5);

            plot(app.UIAxes,xmax0down,ymax0down,'bo','MarkerSize',5);
            legend(app.UIAxes,'Real','\theta_4 down','\theta_0 down');
            title(app.UIAxes,'Specific thrust (\psi) - T_{t3prima} trends');
            xlabel(app.UIAxes,'T_{t3prima} [K]');
            ylabel(app.UIAxes,'\psi [m/s]');
            hold(app.UIAxes,'off');

            [maximo_y_2, indice_maximo_2] = max(overall_eff_vec_M0_fixed_real);
            x_maximo_2 = Tt3_prima_val(indice_maximo_2);

            [ymax4down_2, indice_4down_2] = max(overall_eff_vec_M0_fixed_theta4down);
            xmax4down_2 = Tt3_prima_val(indice_4down_2);

            [ymax0down_2, indice_0down_2] = max(overall_eff_vec_M0_fixed_theta0down);
            xmax0down_2 = Tt3_prima_val(indice_0down_2);
            plot(app.UIAxes3,Tt3_prima_val,overall_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,overall_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,overall_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,x_maximo_2,maximo_y_2,'ko','MarkerSize',5);

            plot(app.UIAxes3,xmax4down_2,ymax4down_2,'ro','MarkerSize',5);

            plot(app.UIAxes3,xmax0down_2,ymax0down_2,'bo','MarkerSize',5);
            title(app.UIAxes3,'Overall efficiency (\eta_{O}) - T_{t3prima} trends');
            xlabel(app.UIAxes3,'T_{t3prima} [K]');
            ylabel(app.UIAxes3,'\eta_{O} [-]');
            hold(app.UIAxes3,'off');

            [maximo_y_3, indice_maximo_3] = max(thermal_eff_vec_M0_fixed_real);
            x_maximo_3 = Tt3_prima_val(indice_maximo_3);

            [ymax4down_3, indice_4down_3] = max(thermal_eff_vec_M0_fixed_theta4down);
            xmax4down_3 = Tt3_prima_val(indice_4down_3);

            [ymax0down_3, indice_0down_3] = max(thermal_eff_vec_M0_fixed_theta0down);
            xmax0down_3 = Tt3_prima_val(indice_0down_3);
            plot(app.UIAxes2,Tt3_prima_val,thermal_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,x_maximo_3,maximo_y_3,'ko','MarkerSize',5);

            plot(app.UIAxes2,xmax4down_3,ymax4down_3,'ro','MarkerSize',5);

            plot(app.UIAxes2,xmax0down_3,ymax0down_3,'bo','MarkerSize',5);
            title(app.UIAxes2,'Thermal efficiency (\eta_{th}) - T_{t3prima} trends')
            xlabel(app.UIAxes2,'T_{t3prima} [K]')
            ylabel(app.UIAxes2,'\eta_{th} [-]')
            hold(app.UIAxes2,'off');

            [minimo_y, indice_minimo] = min(prop_eff_vec_M0_fixed_real);
            x_minimo = Tt3_prima_val(indice_minimo);

            [ymin4down, indice_4down_4] = min(prop_eff_vec_M0_fixed_theta4down);
            xmin4down = Tt3_prima_val(indice_4down_4);

            [ymin0down, indice_0down_4] = min(prop_eff_vec_M0_fixed_theta0down);
            xmin0down = Tt3_prima_val(indice_0down_4);
            plot(app.UIAxes4,Tt3_prima_val,prop_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,prop_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,prop_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,x_minimo,minimo_y,'ko','MarkerSize',5);

            plot(app.UIAxes4,xmin4down,ymin4down,'ro','MarkerSize',5);

            plot(app.UIAxes4,xmin0down,ymin0down,'bo','MarkerSize',5);
            title(app.UIAxes4,'Propulsive efficiency (\eta_{pr}) - T_{t3prima} trends')
            xlabel(app.UIAxes4,'T_{t3prima} [K]')
            ylabel(app.UIAxes4,'\eta_{pr} [-]')
            hold(app.UIAxes4,'off');
            
            plot(app.UIAxes2_2,Tt3_prima_val,C_ts_vec_M0_fixed_real,'k-',Tt3_prima_val,C_ts_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,C_ts_vec_M0_fixed_theta0down,'b--');

            title(app.UIAxes2_2,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes2_2,'T_{t3prima} [K]')
            ylabel(app.UIAxes2_2,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_2);

            [minimo_y_4, indice_minimo_4] = min(W_vec_M0_fixed_real);
            x_minimo_4 = Tt3_prima_val(indice_minimo_4);

            [ymin4down_2, indice_4down_4_2] = min(W_vec_M0_fixed_theta4down);
            xmin4down_2 = Tt3_prima_val(indice_4down_4_2);

            [ymin0down_2, indice_0down_4_2] = min(W_vec_M0_fixed_theta0down);
            xmin0down_2 = Tt3_prima_val(indice_0down_4_2);
            plot(app.UIAxes5,Tt3_prima_val,W_vec_M0_fixed_real,'k-',Tt3_prima_val,W_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,W_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,x_minimo_4,minimo_y_4,'ko','MarkerSize',5);

            plot(app.UIAxes5,xmin4down_2,ymin4down_2,'ro','MarkerSize',5);

            plot(app.UIAxes5,xmin0down_2,ymin0down_2,'bo','MarkerSize',5);
            title(app.UIAxes5,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes5,'T_{t3prima} [K]')
            ylabel(app.UIAxes5,'W_s [kJ]')
            hold(app.UIAxes5,'off');


%% COLORMAP
            Tt25_val_map = Tt25_val_opt-200:20:Tt25_val_opt+600;
            Delta_Tt25 =Tt25_val_opt-Tt0;
            Delta_Tt25_val_map = Delta_Tt25-200:20:Delta_Tt25+600;
            Tt3_prima_val_map = Tt3_prima_val_opt-200:20:Tt3_prima_val_opt+600;
            Delta_Tt3p =Tt3_prima_val_opt-Tt25_p;
            Delta_Tt3p_val_map = Delta_Tt3p-200:20:Delta_Tt3p+600;
            W_vec_real = zeros(length(Tt3_prima_val_map),length(Tt25_val_map));
            i=1;
            while i<length(Tt25_val_map)
                j=1;
                while j<length(Tt3_prima_val_map)
                    [~,~,~,~,~,~,W_vec_real(i,j)] = CALCULATION_IC_TT3P(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val_map(j),0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val_map(i));
                    j=j+1;
                end
                i=i+1;
            end
            
            contour(app.UIAxes14,Delta_Tt25_val_map,Delta_Tt3p_val_map,W_vec_real,500);
            hold(app.UIAxes14,'on');
            title(app.UIAxes14,'Work specific output vs both \DeltaT in compressor')
            xlabel(app.UIAxes14,'\DeltaT_{t25} [K]')
            ylabel(app.UIAxes14,'\DeltaT_{t3prima} [K]')
            colormap(app.UIAxes14,"jet");
            colorbar(app.UIAxes14,"eastoutside")
            hold(app.UIAxes14,'off');
            app.SimulationperformedCheckBox.Value = true;

           
        end

        % Button pushed function: Realparameters
        function RealparametersPushed(app, event)


            app.epsilon_iEditField.Value=0.075;
            app.epsilon_bEditField_2.Value=0.06;
            app.epsilon_nEditField.Value=0.02;
            app.eta_cpEditField.Value=0.88;
            app.eta_tpEditField.Value=0.93;
            app.DeltaT_interEditField.Value=0;
            
            app.xEditField.Value=0.07;
            app.phiEditField.Value=0.98;
            app.SimulationperformedCheckBox.Value = false;
            app.SimulationPerformedIdealCheckBox.Value = false;
            app.ComparisonperformedCheckBox.Value = false;
            app.SimulationperformedCheckBox_2.Value = false;
            app.SimulationPerformedIdealCheckBox_2.Value = false;
            app.ComparisonperformedCheckBox_2.Value = false;
            app.ValuesfoundCheckBox.Value = false;

            cla(app.UIAxes, 'reset'); % Clear axes control.
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes2_2, 'reset');

            cla(app.UIAxes12, 'reset');
            cla(app.UIAxes13, 'reset');

            cla(app.UIAxes6, 'reset'); 
            cla(app.UIAxes7, 'reset');
            cla(app.UIAxes8, 'reset');
            cla(app.UIAxes9, 'reset');
            cla(app.UIAxes10, 'reset');
            cla(app.UIAxes11, 'reset');

            cla(app.UIAxes_2, 'reset'); 
            cla(app.UIAxes2_3, 'reset');
            cla(app.UIAxes3_2, 'reset');
            cla(app.UIAxes4_2, 'reset');
            cla(app.UIAxes5_2, 'reset');
            cla(app.UIAxes2_4, 'reset');

            cla(app.UIAxes_3, 'reset'); 
            cla(app.UIAxes2_5, 'reset');
            cla(app.UIAxes3_3, 'reset');
            cla(app.UIAxes4_3, 'reset');
            cla(app.UIAxes5_3, 'reset');
            cla(app.UIAxes2_6, 'reset');

            cla(app.UIAxes6_2, 'reset'); 
            cla(app.UIAxes7_2, 'reset');
            cla(app.UIAxes8_2, 'reset');
            cla(app.UIAxes9_2, 'reset');
            cla(app.UIAxes10_2, 'reset');
            cla(app.UIAxes11_2, 'reset');

%             app.EditField_18.Value=0;
%             app.EditField_19.Value=0;
%             app.EditField_20.Value=0;
%             app.W_skJEditField.Value = 0;
%             
% 
%             app.EditField_21.Value=0;
%             app.EditField_22.Value=0;
%             app.EditField_23.Value=0;
%             app.EditField_24.Value=0;
%             app.EditField_25.Value=0;
% 
%             app.T_t25EditField.Value=0;
%             app.T_t25EditField_2.Value=0;
%             app.P_t25EditField.Value0;
%             app.P_t25EditField_2.Value=0;
%             
%             app.EditField.Value = 0;
%             app.EditField_2.Value  = 0;
%             app.EditField_3.Value  = 0;
%             app.EditField_4.Value  = 0;
%             app.EditField_5.Value  = 0;
%             app.EditField_6.Value  = 0;
%             app.EditField_7.Value  = 0;
%             app.EditField_8.Value  = 0;
%             app.EditField_9.Value  = 0;
%             app.EditField_10.Value  = 0;
%             app.EditField_11.Value  = 0;
%             app.EditField_12.Value  = 0;
%             app.EditField_13.Value  = 0;
%             app.EditField_14.Value  = 0;
%             app.EditField_15.Value  = 0;
%             app.EditField_16.Value  = 0;
%             app.EditField_17.Value  = 0;
% 
            app.EditField_26.Value=0;
            app.EditField_27.Value=0;
            app.EditField_32.Value=0;
            app.EditField_33.Value=0;

            
            app.TurbojetcyclewithidealintercoolerButton.Enable= "off";
            app.TurbojetcyclewithrealintercoolerButton.Enable= "off";

%             app.EditField_51.Value=0;
%             app.EditField_52.Value=0;
%             app.EditField_53.Value=0;
%             app.WkJEditField_2.Value = 0;
%             
%             
%             app.EditField_54.Value=0;
%             app.EditField_55.Value=0;
%             app.EditField_56.Value=0;
%             app.EditField_57.Value=0;
%             app.EditField_58.Value=0;
% 
%             
%             app.EditField_34.Value = 0;
%             app.EditField_35.Value  = 0;
%             app.EditField_36.Value  = 0;
%             app.EditField_37.Value  = 0;
%             app.EditField_38.Value  = 0;
%             app.EditField_39.Value  = 0;
%             app.EditField_40.Value  = 0;
%             app.EditField_41.Value  = 0;
%             app.EditField_42.Value  = 0;
%             app.EditField_43.Value  = 0;
%             app.EditField_44.Value  = 0;
%             app.EditField_45.Value  = 0;
%             app.EditField_46.Value  = 0;
%             app.EditField_47.Value  = 0;
%             app.EditField_48.Value  = 0;
%             app.EditField_49.Value  = 0;
%             app.EditField_50.Value  = 0;
%             app.M_2EditField.Value = 0;
        end

        % Button pushed function: Idealparameters
        function IdealparametersButtonPushed(app, event)


            app.epsilon_iEditField.Value=0;
            app.epsilon_bEditField_2.Value=0;
            app.epsilon_nEditField.Value=0;
            app.eta_cpEditField.Value=1;
            app.eta_tpEditField.Value=1;
            app.DeltaT_interEditField.Value=0;
            
            app.xEditField.Value=0;
            app.phiEditField.Value=1;
            app.SimulationperformedCheckBox.Value = false;
            app.SimulationPerformedIdealCheckBox.Value = false;
            app.ComparisonperformedCheckBox.Value = false;
            app.SimulationperformedCheckBox_2.Value = false;
            app.SimulationPerformedIdealCheckBox_2.Value = false;
            app.ComparisonperformedCheckBox_2.Value = false;
            app.ValuesfoundCheckBox.Value = false;

            cla(app.UIAxes, 'reset'); % Clear axes control.
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes2_2, 'reset');

            cla(app.UIAxes12, 'reset');
            cla(app.UIAxes13, 'reset');

            cla(app.UIAxes6, 'reset'); 
            cla(app.UIAxes7, 'reset');
            cla(app.UIAxes8, 'reset');
            cla(app.UIAxes9, 'reset');
            cla(app.UIAxes10, 'reset');
            cla(app.UIAxes11, 'reset');

            cla(app.UIAxes_2, 'reset'); 
            cla(app.UIAxes2_3, 'reset');
            cla(app.UIAxes3_2, 'reset');
            cla(app.UIAxes4_2, 'reset');
            cla(app.UIAxes5_2, 'reset');
            cla(app.UIAxes2_4, 'reset');

            cla(app.UIAxes_3, 'reset'); 
            cla(app.UIAxes2_5, 'reset');
            cla(app.UIAxes3_3, 'reset');
            cla(app.UIAxes4_3, 'reset');
            cla(app.UIAxes5_3, 'reset');
            cla(app.UIAxes2_6, 'reset');

            cla(app.UIAxes6_2, 'reset'); 
            cla(app.UIAxes7_2, 'reset');
            cla(app.UIAxes8_2, 'reset');
            cla(app.UIAxes9_2, 'reset');
            cla(app.UIAxes10_2, 'reset');
            cla(app.UIAxes11_2, 'reset');

%             app.EditField_18.Value=0;
%             app.EditField_19.Value=0;
%             app.EditField_20.Value=0;
%             app.W_skJEditField.Value = 0;
%             
% 
%             app.EditField_21.Value=0;
%             app.EditField_22.Value=0;
%             app.EditField_23.Value=0;
%             app.EditField_24.Value=0;
%             app.EditField_25.Value=0;
% 
%             app.T_t25EditField.Value=0;
%             app.T_t25EditField_2.Value=0;
%             app.P_t25EditField.Value0;
%             app.P_t25EditField_2.Value=0;
%             
%             app.EditField.Value = 0;
%             app.EditField_2.Value  = 0;
%             app.EditField_3.Value  = 0;
%             app.EditField_4.Value  = 0;
%             app.EditField_5.Value  = 0;
%             app.EditField_6.Value  = 0;
%             app.EditField_7.Value  = 0;
%             app.EditField_8.Value  = 0;
%             app.EditField_9.Value  = 0;
%             app.EditField_10.Value  = 0;
%             app.EditField_11.Value  = 0;
%             app.EditField_12.Value  = 0;
%             app.EditField_13.Value  = 0;
%             app.EditField_14.Value  = 0;
%             app.EditField_15.Value  = 0;
%             app.EditField_16.Value  = 0;
%             app.EditField_17.Value  = 0;
% 
            app.EditField_26.Value=0;
            app.EditField_27.Value=0;
            app.EditField_32.Value=0;
            app.EditField_33.Value=0;

            
            app.TurbojetcyclewithidealintercoolerButton.Enable= "off";
            app.TurbojetcyclewithrealintercoolerButton.Enable= "off";

%             app.EditField_51.Value=0;
%             app.EditField_52.Value=0;
%             app.EditField_53.Value=0;
%             app.WkJEditField_2.Value = 0;
%             
%             
%             app.EditField_54.Value=0;
%             app.EditField_55.Value=0;
%             app.EditField_56.Value=0;
%             app.EditField_57.Value=0;
%             app.EditField_58.Value=0;
% 
%             
%             app.EditField_34.Value = 0;
%             app.EditField_35.Value  = 0;
%             app.EditField_36.Value  = 0;
%             app.EditField_37.Value  = 0;
%             app.EditField_38.Value  = 0;
%             app.EditField_39.Value  = 0;
%             app.EditField_40.Value  = 0;
%             app.EditField_41.Value  = 0;
%             app.EditField_42.Value  = 0;
%             app.EditField_43.Value  = 0;
%             app.EditField_44.Value  = 0;
%             app.EditField_45.Value  = 0;
%             app.EditField_46.Value  = 0;
%             app.EditField_47.Value  = 0;
%             app.EditField_48.Value  = 0;
%             app.EditField_49.Value  = 0;
%             app.EditField_50.Value  = 0;
%             app.M_2EditField.Value = 0;
        end

        % Button pushed function: TurbojetcyclewithprecoolingButton
        function TurbojetcyclewithprecoolingButtonPushed(app, event)
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            M_2=app.M_2EditField_2.Value;
            tau_c =app.tau_cEditField.Value;
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            Tt3_prima_val=0;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt3_prima,Pt3,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_PC_ID(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c);
       
            %app.UITable.RowName={'Specific Thrust','C_{TS}','\Delta K_{c}'};


            app.EditField_51.Value=specific_thrust;
            app.EditField_52.Value=C_ts*1000000;
            app.EditField_53.Value=Deltak_c/1000;
            app.WkJEditField_2.Value = w_s/1000;
            
            %app.UITable_2.RowName={'Overall efficiency', 'Propulsive efficiency','Thermal efficiency'};
            app.EditField_54.Value=overall_eff;
            app.EditField_55.Value=prop_eff;
            app.EditField_56.Value=thermal_eff;
            app.EditField_57.Value=Tt4;
            app.EditField_58.Value=V0;

            
            app.EditField_34.Value = Tt0;
            app.EditField_35.Value  = Pt0/1000;
            app.EditField_36.Value  = pi_fs;
            app.EditField_37.Value  = Tt2;
            app.EditField_38.Value  = Pt2/1000;
            app.EditField_39.Value  = Tt3_prima;
            app.EditField_40.Value  = Pt3/1000;
            app.EditField_41.Value  = pi_c;
            app.EditField_42.Value  = Pt4/1000;
            app.EditField_43.Value  = alpha_b;
            app.EditField_44.Value  = Tt5;
            app.EditField_45.Value  = Pt5/1000;
            app.EditField_46.Value  = alpha_prima;
            app.EditField_47.Value  = T9;
            app.EditField_48.Value  = Pt9/1000;
            app.EditField_49.Value  = pi_9;
            app.EditField_50.Value  = V9;
            app.M_2EditField.Value = M_2;

            Tt4_values=1900:200:2300;
            Tt3_prima_val=Tt3_prima-200:1:Tt3_prima+800;
            

            specific_thrust_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));

            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real(i_1)>0,i_1<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_real(i_1),overall_eff_vec_M0_fixed_real(i_1),C_ts_vec_M0_fixed_real(i_1),prop_eff_vec_M0_fixed_real(i_1),thermal_eff_vec_M0_fixed_real(i_1),deltak_c,W_vec_M0_fixed_real(i_1)] = CALCULATION_PC_ID(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c);
                i_1=i_1+1;
                
            end
            M_2_val = 1:0.01:M_0;
            specific_thrust_vec_M2_val=zeros(1,length(M_2_val));
            overall_eff_vec_M2_val=zeros(1,length(M_2_val));
            C_ts_vec_M2_val=zeros(1,length(M_2_val));
            prop_eff_vec_M2_val=zeros(1,length(M_2_val));
            thermal_eff_vec_M2_val=zeros(1,length(M_2_val));
            W_vec_M2_val=zeros(1,length(M_2_val));

            i_2=1;
            while or(specific_thrust_vec_M2_val(i_2)>0,i_2<length(M_2_val)) 
                [specific_thrust_vec_M2_val(i_2),overall_eff_vec_M2_val(i_2),C_ts_vec_M2_val(i_2),prop_eff_vec_M2_val(i_2),thermal_eff_vec_M2_val(i_2),deltak_c,W_vec_M2_val(i_2)] = CALCULATION_PC_ID(M_0,T_0,gamma,r,alpha,P_0,e_i,0,eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2_val(i_2),tau_c);
                i_2=i_2+1;
                
            end

            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;r25=0;r26=0;r27=0;r28=0;


            for i=1:length(prop_eff_vec_M0_fixed_real)
                
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r3==1)
                    if r3==0
                        specific_thrust_vec_M0_fixed_real(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r3=1;
                    else
                        specific_thrust_vec_M0_fixed_real(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real(i)=NaN;
                    r4=1;
                end


                if or(prop_eff_vec_M0_fixed_real(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

    
                if or(overall_eff_vec_M0_fixed_real(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real(i)=1;
                        r6=1;
%                     else
%                         overall_eff_vec_M0_fixed_real(i)=1;
                    end
                end

                 if or( W_vec_M0_fixed_real(i)>=0,r26==1)
                    if r26==0
                        W_vec_M0_fixed_real(i)=0;
                        r26=1;
                    else
                        W_vec_M0_fixed_real(i)=NaN;
                    end
                end
                
            end

            for j=1:length(prop_eff_vec_M2_val)
                
                if or(specific_thrust_vec_M2_val(j)<=0,r5==1)
                    if r5==0
                        specific_thrust_vec_M2_val(j)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r5=1;
                    else
                        specific_thrust_vec_M2_val(j)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M2_val(j)<=0,r8==1)
                    if r8==0
                        thermal_eff_vec_M2_val(j)=0;
                        r8=1;
                    else
                        thermal_eff_vec_M2_val(j)=NaN;
                    end
                end

                if or(thermal_eff_vec_M2_val(j)>=1,r9==1)
                    thermal_eff_vec_M2_val(j)=1;
                end

                if j>1
                    if or (thermal_eff_vec_M2_val(j)>=1 && thermal_eff_vec_M2_val(j-1)>=1,thermal_eff_vec_M2_val(j)>=1 && isnan(thermal_eff_vec_M2_val(j-1)))
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if thermal_eff_vec_M2_val(j)>=1
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                end
                if or(C_ts_vec_M2_val(j)<=0,r11==0)
                    C_ts_vec_M2_val(j)=NaN;
                    r11=1;
                end


                if or(prop_eff_vec_M2_val(j)>=1,r12==1)
                    if r12==0
                        prop_eff_vec_M2_val(j)=1;
                        r12=1;
                    else
                        prop_eff_vec_M2_val(j)=NaN;
                    end
                end

    
                if or(overall_eff_vec_M2_val(j)<=0,r13==1)
                    if r13==0
                        overall_eff_vec_M2_val(j)=0;
                        r13=1;
                    else
                        overall_eff_vec_M2_val(j)=NaN;
                    end
                end

                if j>1
                    if or (overall_eff_vec_M2_val(j)>=1 && overall_eff_vec_M2_val(j-1)>=1,overall_eff_vec_M2_val(j)>=1 && isnan(overall_eff_vec_M2_val(j-1)))
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if overall_eff_vec_M2_val(j)>=1
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                end

                if or( W_vec_M2_val(j)>=0,r27==1)
                    if r27==0
                        W_vec_M2_val(j)=0;
                        r27=1;
                    else
                        W_vec_M2_val(j)=NaN;
                    end
                end
                
            end
            
 %% GRAPHS TT3'
            [maximo_y, indice_maximo] = max(specific_thrust_vec_M0_fixed_real);
            x_maximo = Tt3_prima_val(indice_maximo);

            plot(app.UIAxes_2,Tt3_prima_val,specific_thrust_vec_M0_fixed_real,'k-');

            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,x_maximo,maximo_y,'ko','MarkerSize',5);
            legend(app.UIAxes_2,'Real');
            title(app.UIAxes_2,'Specific thrust (\psi) - T_{t3prima} trends');
            xlabel(app.UIAxes_2,'T_{t3prima} [K]');
            ylabel(app.UIAxes_2,'\psi [m/s]');
            hold(app.UIAxes_2,'off');

            [maximo_y_2, indice_maximo_2] = max(overall_eff_vec_M0_fixed_real);
            x_maximo_2 = Tt3_prima_val(indice_maximo_2);

            plot(app.UIAxes3_2,Tt3_prima_val,overall_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes3_2,'on');
            plot(app.UIAxes3_2,x_maximo_2,maximo_y_2,'ko','MarkerSize',5);
            title(app.UIAxes3_2,'Overall efficiency (\eta_{O}) - T_{t3prima} trends');
            xlabel(app.UIAxes3_2,'T_{t3prima} [K]');
            ylabel(app.UIAxes3_2,'\eta_{O} [-]');
            hold(app.UIAxes3_2,'off');

            [maximo_y_3, indice_maximo_3] = max(thermal_eff_vec_M0_fixed_real);
            x_maximo_3 = Tt3_prima_val(indice_maximo_3);
            plot(app.UIAxes2_3,Tt3_prima_val,thermal_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes2_3,'on');
            plot(app.UIAxes2_3,x_maximo_3,maximo_y_3,'ko','MarkerSize',5);
            title(app.UIAxes2_3,'Thermal efficiency (\eta_{th}) - T_{t3prima} trends')
            xlabel(app.UIAxes2_3,'T_{t3prima} [K]')
            ylabel(app.UIAxes2_3,'\eta_{th} [-]')
            hold(app.UIAxes2_3,'off');

            [minimo_y, indice_minimo] = min(prop_eff_vec_M0_fixed_real);
            x_minimo = Tt3_prima_val(indice_minimo);
            plot(app.UIAxes4_2,Tt3_prima_val,prop_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2,x_minimo,minimo_y,'ko','MarkerSize',5);
            title(app.UIAxes4_2,'Propulsive efficiency (\eta_{pr}) - T_{t3prima} trends')
            xlabel(app.UIAxes4_2,'T_{t3prima} [K]')
            ylabel(app.UIAxes4_2,'\eta_{pr} [-]')
            hold(app.UIAxes4_2,'off');
            
            plot(app.UIAxes2_4,Tt3_prima_val,C_ts_vec_M0_fixed_real,'k-');
            title(app.UIAxes2_4,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes2_4,'T_{t3prima} [K]')
            ylabel(app.UIAxes2_4,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_4);

            [minimo_y_4, indice_minimo_4] = min(W_vec_M0_fixed_real);
            x_minimo_4 = Tt3_prima_val(indice_minimo_4);
            plot(app.UIAxes5_2,Tt3_prima_val,W_vec_M0_fixed_real,'k-');
            hold(app.UIAxes5_2,'on');
            plot(app.UIAxes5_2,x_minimo_4,minimo_y_4,'ko','MarkerSize',5);
            title(app.UIAxes5_2,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes5_2,'T_{t3prima} [K]')
            ylabel(app.UIAxes5_2,'w_s [kJ]')
            hold(app.UIAxes5_2,'off');

%% GRAPHS M_2

            [maximo_yM2, indice_maximoM2] = max(specific_thrust_vec_M2_val);
            x_maximoM2 = M_2_val(indice_maximoM2);

            plot(app.UIAxes_3,M_2_val,specific_thrust_vec_M2_val,'k-');
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,x_maximoM2,maximo_yM2,'ko','MarkerSize',5);
            legend(app.UIAxes_3,'Real');
            title(app.UIAxes_3,'Specific thrust (\psi) - M_2 trends');
            xlabel(app.UIAxes_3,'M_2 [-]');
            ylabel(app.UIAxes_3,'\psi [m/s]');
            hold(app.UIAxes_3,'off');

            [maximo_y_2M2, indice_maximo_2M2] = max(overall_eff_vec_M2_val);
            x_maximo_2M2 = M_2_val(indice_maximo_2M2);
            plot(app.UIAxes3_3,M_2_val,overall_eff_vec_M2_val,'k-');
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,x_maximo_2M2,maximo_y_2M2,'ko','MarkerSize',5);
            title(app.UIAxes3_3,'Overall efficiency (\eta_{O}) - M_2 trends');
            xlabel(app.UIAxes3_3,'M_2 [-]');
            ylabel(app.UIAxes3_3,'\eta_{O} [-]');
            hold(app.UIAxes3_3,'off');

            [maximo_y_3M2, indice_maximo_3M2] = max(thermal_eff_vec_M2_val);
            x_maximo_3M2 = M_2_val(indice_maximo_3M2);
            plot(app.UIAxes2_5,M_2_val,thermal_eff_vec_M2_val,'k-');
            hold(app.UIAxes2_5,'on');
            plot(app.UIAxes2_5,x_maximo_3M2,maximo_y_3M2,'ko','MarkerSize',5);
            title(app.UIAxes2_5,'Thermal efficiency (\eta_{th}) - M_2 trends')
            xlabel(app.UIAxes2_5,'M_2 [-]')
            ylabel(app.UIAxes2_5,'\eta_{th} [-]')
            hold(app.UIAxes2_5,'off');

            [minimo_yM2, indice_minimoM2] = min(prop_eff_vec_M2_val);
            x_minimoM2 = M_2_val(indice_minimoM2);
            plot(app.UIAxes4_3,M_2_val,prop_eff_vec_M2_val,'k-');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3,x_minimoM2,minimo_yM2,'ko','MarkerSize',5);
            title(app.UIAxes4_3,'Propulsive efficiency (\eta_{pr}) - M_2 trends')
            xlabel(app.UIAxes4_3,'M_2 [-]')
            ylabel(app.UIAxes4_3,'\eta_{pr} [-]')
            hold(app.UIAxes4_3,'off');
            
            plot(app.UIAxes2_6,M_2_val,C_ts_vec_M2_val,'k-');
            title(app.UIAxes2_6,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes2_6,'M_2 [-]')
            ylabel(app.UIAxes2_6,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_6);

            [minimo_y_4M2, indice_minimo_4M2] = min(W_vec_M2_val);
            x_minimo_4M2 = M_2_val(indice_minimo_4M2);
            plot(app.UIAxes5_3,M_2_val,W_vec_M2_val,'k-');
            hold(app.UIAxes5_3,'on');
            plot(app.UIAxes5_3,x_minimo_4M2,minimo_y_4M2,'ko','MarkerSize',5);
            title(app.UIAxes5_3,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes5_3,'M_2 [-]')
            ylabel(app.UIAxes5_3,'W_s [kJ]')
            hold(app.UIAxes5_3,'off');

%% FINISHED
            app.SimulationPerformedIdealCheckBox_2.Value= true;
        end

        % Button pushed function: OptimizedintercoolingvaluesButton
        function OptimizedintercoolingvaluesButtonPushed(app, event)
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
         
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            Tt3_prima_val=0;
            Tt25_val=0;

            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25,Pt25,Tt25_p,Pt25_p,Tt3_prima,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val,0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val);

%% 1ST ITERATION
            Tt3_prima_val_1st=Tt3_prima-200:1:Tt3_prima+200;
            specific_thrust_1st=zeros(1,length(Tt3_prima_val_1st));
            w_s_1st=zeros(1,length(Tt3_prima_val_1st));

            i=1;
            while or (specific_thrust_1st(i)>0, i<length(Tt3_prima_val_1st))
                [specific_thrust_1st(i),~,~,~,~,~,w_s_1st(i),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25,Pt25,Tt25_p,Pt25_p,Tt3_prima_1st,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val_1st(i),0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val);
                i=i+1;
            end

            [minimo_1st, i_min_1st] = min(w_s_1st);
            Tt3_prima_min_1st = Tt3_prima_val_1st(i_min_1st);
            
%% 2ND ITERATION

            Tt25_val_2nd=Tt25-200:1:Tt25+200;
            specific_thrust_2nd=zeros(1,length(Tt25_val_2nd));
            w_s_2nd=zeros(1,length(Tt25_val_2nd));

            j=1;
            while or (specific_thrust_2nd(j)>0, j<length(Tt25_val_2nd))
                [specific_thrust_2nd(j),~,~,~,~,~,w_s_2nd(j),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25_2nd,Pt25,Tt25_p,Pt25_p,Tt3_prima_2nd,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_min_1st,0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val_2nd(j));
                j=j+1;
            end

            [minimo_2nd, i_min_2nd] = min(w_s_2nd);
            Tt25_min_2nd = Tt25_val_2nd(i_min_2nd);

%% 3RD ITERATION

            Tt3_prima_val_3rd=Tt3_prima_min_1st-200:1:Tt3_prima_min_1st+200;
            specific_thrust_3rd=zeros(1,length(Tt3_prima_val_3rd));
            w_s_3rd=zeros(1,length(Tt3_prima_val_3rd));

            k=1;
            while or (specific_thrust_3rd(k)>0 , k<length(Tt3_prima_val_3rd))
                [specific_thrust_3rd(k),~,~,~,~,~,w_s_3rd(k),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25_3rd,Pt25,Tt25_p,Pt25_p,Tt3_prima_3rd,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val_3rd(k),0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_min_2nd);
                k=k+1;
            end

            [minimo_3rd, i_min_3rd] = min(w_s_3rd);
            Tt3_prima_min_3rd = Tt3_prima_val_3rd(i_min_3rd);

%% 4TH ITERATION 

            Tt25_val_4th=Tt25_min_2nd-200:1:Tt25_min_2nd+200;
            specific_thrust_4th=zeros(1,length(Tt25_val_4th));
            w_s_4th=zeros(1,length(Tt25_val_4th));

            m=1;
            while or (specific_thrust_4th(m)>0 , m<length(Tt25_val_4th))
                [specific_thrust_4th(m),~,~,~,~,~,w_s_4th(m),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25_4th,Pt25,Tt25_p,Pt25_p,Tt3_prima_4th,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_min_3rd,0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val_4th(m));
                m=m+1;
            end

            [minimo_4th, i_min_4th] = min(w_s_4th);
            Tt25_min_4th = Tt25_val_4th(i_min_4th);
%% 5TH ITERATION

            Tt3_prima_val_5th=Tt3_prima_min_3rd-200:1:Tt3_prima_min_3rd+200;
            specific_thrust_5th=zeros(1,length(Tt3_prima_val_5th));
            w_s_5th=zeros(1,length(Tt3_prima_val_5th));

            n=1;
            while or (specific_thrust_3rd(n)>0 , n<length(Tt3_prima_val_5th))
                [specific_thrust_5th(n),~,~,~,~,~,w_s_5th(n),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25_3rd,Pt25,Tt25_p,Pt25_p,Tt3_prima_3rd,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val_5th(n),0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_min_4th);
                n=n+1;
            end

            [minimo_5th, i_min_5th] = min(w_s_5th);
            Tt3_prima_min_5th = Tt3_prima_val_5th(i_min_5th);

%% 6TH ITERATION 

            Tt25_val_6th=Tt25_min_4th-200:1:Tt25_min_4th+200;
            specific_thrust_6th=zeros(1,length(Tt25_val_6th));
            w_s_6th=zeros(1,length(Tt25_val_6th));

            p=1;
            while or (specific_thrust_4th(p)>0 , p<length(Tt25_val_6th))
                [specific_thrust_6th(p),~,~,~,~,~,w_s_6th(p),Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25_4th,Pt25,Tt25_p,Pt25_p,Tt3_prima_4th,Pt3_prima,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_TT3P(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_min_5th,0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val_6th(p));
                p=p+1;
            end

            [minimo_6th, i_min_6th] = min(w_s_6th);
            Tt25_min_6th = Tt25_val_6th(i_min_6th);
%% ASSIGNATION
            app.EditField_26.Value=Tt3_prima_min_1st;
            app.EditField_27.Value=Tt25;
 
            app.EditField_32.Value=Tt3_prima_min_5th;
            app.EditField_33.Value=Tt25_min_6th;

            app.ValuesfoundCheckBox.Value = true;
            app.TurbojetcyclewithidealintercoolerButton.Enable= "on";
            app.TurbojetcyclewithrealintercoolerButton.Enable= "on";
        end

        % Value changed function: PressurelossesDropDown
        function PressurelossesDropDownValueChanged(app, event)
            VALUE_1= app.PressurelossesDropDown.Value;
            P_LOSS = str2double(VALUE_1);
            app.e_interEditField.Value=P_LOSS/100;
        end

        % Value changed function: DeltaTnotcooledDropDown
        function DeltaTnotcooledDropDownValueChanged(app, event)
            VALUE_2= app.DeltaTnotcooledDropDown.Value;
            DELTA_INTER = str2double(VALUE_2);
            app.DeltaT_interEditField.Value=DELTA_INTER/100;
        end

        % Button pushed function: TurbojetcyclewithrealintercoolerButton
        function TurbojetcyclewithrealintercoolerButtonPushed(app, event)
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            e_interp=app.e_interEditField.Value;
            diff_T=app.DeltaT_interEditField.Value;
            Delta_T_Pc_values=0;
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            
            Tt3_prima_val_opt2 = app.EditField_32.Value;
            Tt25_val_opt2 = app.EditField_33.Value;

            M0_values=[M_0-1,M_0];
            Tt4_values=1900:200:2300;
            Tt3_prima_val2=Tt3_prima_val_opt2-200:1:Tt3_prima_val_opt2+1370;
            Delta_T_Pc_Values=0;


            specific_thrust_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));

            specific_thrust_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));

            specific_thrust_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            
            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real_2(i_1)>0,i_1<length(Tt3_prima_val2)) 
                [specific_thrust_vec_M0_fixed_real_2(i_1),overall_eff_vec_M0_fixed_real_2(i_1),C_ts_vec_M0_fixed_real_2(i_1),prop_eff_vec_M0_fixed_real_2(i_1),thermal_eff_vec_M0_fixed_real_2(i_1),deltak_c,W_vec_M0_fixed_real_2(i_1)] = CALCULATION_IC_TT3P(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_1),Delta_T_Pc_Values,eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_val_opt2);
                i_1=i_1+1;
            end
            i_2=1;
            while or(specific_thrust_vec_M0_fixed_reali(i_2)>0,i_2<length(Tt3_prima_val2)) 
                [specific_thrust_vec_M0_fixed_reali(i_2),overall_eff_vec_M0_fixed_reali(i_2),C_ts_vec_M0_fixed_reali(i_2),prop_eff_vec_M0_fixed_reali(i_2),thermal_eff_vec_M0_fixed_reali(i_2),deltak_c,W_vec_M0_fixed_reali(i_2)] = CALCULATION_IC_TT3P(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_2),Delta_T_Pc_Values,eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_val_opt2);
                i_2=i_2+1; 
            end
            i_3=1;
            while or(specific_thrust_vec_M0_fixed_real_no_mod(i_3)>0,i_3<length(Tt3_prima_val2))
                [specific_thrust_vec_M0_fixed_real_no_mod(i_3),overall_eff_vec_M0_fixed_real_no_mod(i_3),C_ts_vec_M0_fixed_real_no_mod(i_3),prop_eff_vec_M0_fixed_real_no_mod(i_3),thermal_eff_vec_M0_fixed_real_no_mod(i_3),deltak_c,W_vec_M0_fixed_real_no_mod(i_3)] = CALCULATION(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_3),eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n);
                i_3=i_3+1;
            end
            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;
            for i=1:length(prop_eff_vec_M0_fixed_real_2)
                
                if or(specific_thrust_vec_M0_fixed_real_2(i)<=0,r21==1)
                    if r21==0
                        specific_thrust_vec_M0_fixed_real_2(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r21=1;
                    else
                        specific_thrust_vec_M0_fixed_real_2(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real_2(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real_2(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real_2(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real_2(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real_2(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real_2(i)=NaN;
                    r4=1;
                end
    
                
                if or(prop_eff_vec_M0_fixed_real_2(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real_2(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end


                if or(specific_thrust_vec_M0_fixed_real_2(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real_2(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real_2(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real_2(i)=1;
                        r6=1;
                    else
                        overall_eff_vec_M0_fixed_real_2(i)=1;
                    end
                end

                if or( W_vec_M0_fixed_real_2(i)>=0,r15==1)
                    if r15==0
                        W_vec_M0_fixed_real_2(i)=0;
                        r15=1;
                    else
                        W_vec_M0_fixed_real_2(i)=NaN;
                    end
                end
                
            end

            for j=1:length(prop_eff_vec_M0_fixed_reali)
                
                if or(specific_thrust_vec_M0_fixed_reali(j)<=0,r3==1)
                    if r3==0
                        specific_thrust_vec_M0_fixed_reali(j)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r3=1;
                    else
                        specific_thrust_vec_M0_fixed_reali(j)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_reali(j)<=0,r5==1)
                    if r5==0
                        thermal_eff_vec_M0_fixed_reali(j)=0;
                        r5=1;
                    else
                        thermal_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_reali(j)>=1,r8==1)
                    if r8==0
                        thermal_eff_vec_M0_fixed_reali(j)=1;
                        r8=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_reali(j)<=0,r9==0)
                    C_ts_vec_M0_fixed_reali(j)=NaN;
                    r9=1;
                end
                if Tt3_prima_val2(j)>=Tt4
                    C_ts_vec_M0_fixed_reali(j)=NaN;
                end
                
                if or(prop_eff_vec_M0_fixed_reali(j)>=1,r11==1)
                    if r11==0
                        prop_eff_vec_M0_fixed_reali(j)=1;
                        r11=1;
                    else
                        prop_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end


                if or(specific_thrust_vec_M0_fixed_reali(j)<=0,r12==1)
                    if r12==0
                        overall_eff_vec_M0_fixed_reali(j)=0;
                        r12=1;
                    else
                        overall_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_reali(j)>=1,r13==1)
                    if r13==0
                        overall_eff_vec_M0_fixed_reali(j)=1;
                        r13=1;
                    else
                        overall_eff_vec_M0_fixed_reali(j)=1;
                    end
                end

                
                if or( W_vec_M0_fixed_reali(j)>=0,r14==1)
                    if r14==0
                        W_vec_M0_fixed_reali(j)=0;
                        r14=1;
                    else
                        W_vec_M0_fixed_reali(j)=NaN;
                    end
                end
                
            end

            for k=1:length(prop_eff_vec_M0_fixed_real_no_mod)
                
                if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r16==1)
                    if r16==0
                        specific_thrust_vec_M0_fixed_real_no_mod(k)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r16=1;
                    else
                        specific_thrust_vec_M0_fixed_real_no_mod(k)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real_no_mod(k)<=0,r17==1)
                    if r17==0
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=0;
                        r17=1;
                    else
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real_no_mod(k)>=1,r18==1)
                    if r18==0
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r18=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real_no_mod(k)<=0,r19==0)
                    C_ts_vec_M0_fixed_real_no_mod(k)=NaN;
                    r19=1;
                end
    
                
                if or(prop_eff_vec_M0_fixed_real_no_mod(k)>=1,r20==1)
                    if r20==0
                        prop_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r20=1;
                    else
                        prop_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end


%                 if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r21==1)
%                     if r21==0
%                         specific_thrust_vec_M0_fixed_real_no_mod(k)=0;
%                         r21=1;
%                     else
%                         specific_thrust_vec_M0_fixed_real_no_mod(k)=NaN;
%                     end
%                 end

                if or(overall_eff_vec_M0_fixed_real_no_mod(k)>=1,r22==1)
                    if r22==0
                        overall_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r22=1;
%                     else
%                         overall_eff_vec_M0_fixed_real_no_mod(k)=1;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r24==1)
                    if r24==0
                        overall_eff_vec_M0_fixed_real_no_mod(k)=0;
                        r24=1;
                    else
                        overall_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end
                
                if or( W_vec_M0_fixed_real_no_mod(k)>=0,r23==1)
                    if r23==0
                        W_vec_M0_fixed_real_no_mod(k)=0;
                        r23=1;
                    else
                        W_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end
                
            end


            [maximo_y_1, indice_maximo_1] = max(specific_thrust_vec_M0_fixed_real_2);
            x_maximo = Tt3_prima_val2(indice_maximo_1);
            [maximo_y_1i, indice_maximo_1i] = max(specific_thrust_vec_M0_fixed_reali);
            x_maximoi = Tt3_prima_val2(indice_maximo_1i);
            [maximo_y_1no, indice_maximo_1no] = max(specific_thrust_vec_M0_fixed_real_no_mod);
            x_maximono = Tt3_prima_val2(indice_maximo_1no);
            plot(app.UIAxes6,Tt3_prima_val2,specific_thrust_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,specific_thrust_vec_M0_fixed_reali,'r--',Tt3_prima_val2,specific_thrust_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6,x_maximo,maximo_y_1,'ko','MarkerSize',5);
            plot(app.UIAxes6,x_maximoi,maximo_y_1i,'ro','MarkerSize',5);
            plot(app.UIAxes6,x_maximono,maximo_y_1no,'bo','MarkerSize',5);
            legend(app.UIAxes6,'Real intercooler','Ideal intercooler','No intercooler');
            title(app.UIAxes6,'Specific thrust (\psi) - T_{t3prima} trends');
            xlabel(app.UIAxes6,'T_{t3} [K]');
            ylabel(app.UIAxes6,'\psi [m/s]');
            hold(app.UIAxes6,'off');


            [maximo_y_2_2, indice_maximo_2_2] = max(overall_eff_vec_M0_fixed_real_2);
            x_maximo_2_2 = Tt3_prima_val2(indice_maximo_2_2);
            [maximo_y_2_2i, indice_maximo_2_2i] = max(overall_eff_vec_M0_fixed_reali);
            x_maximo_2_2i = Tt3_prima_val2(indice_maximo_2_2i);
            [maximo_y_2_2no, indice_maximo_2_2no] = max(overall_eff_vec_M0_fixed_real_no_mod);
            x_maximo_2_2no = Tt3_prima_val2(indice_maximo_2_2no);
            plot(app.UIAxes9,Tt3_prima_val2,overall_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,overall_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,overall_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes9,'on');
            plot(app.UIAxes9,x_maximo_2_2,maximo_y_2_2,'ko','MarkerSize',5);
            plot(app.UIAxes9,x_maximo_2_2i,maximo_y_2_2i,'ro','MarkerSize',5);
            plot(app.UIAxes9,x_maximo_2_2no,maximo_y_2_2no,'bo','MarkerSize',5);
            title(app.UIAxes9,'Overall efficiency (\eta_{O}) - T_{t3prima} trends');
            xlabel(app.UIAxes9,'T_{t3} [K]');
            ylabel(app.UIAxes9,'\eta_{O} [-]');
            hold(app.UIAxes9,'off');

            [maximo_y_3_2, indice_maximo_3_2] = max(thermal_eff_vec_M0_fixed_real_2);
            x_maximo_3_2 = Tt3_prima_val2(indice_maximo_3_2);
            [maximo_y_3_2i, indice_maximo_3_2i] = max(thermal_eff_vec_M0_fixed_reali);
            x_maximo_3_2i = Tt3_prima_val2(indice_maximo_3_2i);
            [maximo_y_3_2no, indice_maximo_3_2no] = max(thermal_eff_vec_M0_fixed_real_no_mod);
            x_maximo_3_2no = Tt3_prima_val2(indice_maximo_3_2no);
            plot(app.UIAxes7,Tt3_prima_val2,thermal_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,thermal_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,thermal_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes7,'on');
            plot(app.UIAxes7,x_maximo_3_2,maximo_y_3_2,'ko','MarkerSize',5);
            plot(app.UIAxes7,x_maximo_3_2i,maximo_y_3_2i,'ro','MarkerSize',5);
            plot(app.UIAxes7,x_maximo_3_2no,maximo_y_3_2no,'bo','MarkerSize',5);
            title(app.UIAxes7,'Thermal efficiency (\eta_{th}) - T_{t3prima} trends')
            xlabel(app.UIAxes7,'T_{t3} [K]')
            ylabel(app.UIAxes7,'\eta_{th} [-]')
            hold(app.UIAxes7,'off');

            [minimo_y__2, indice_minimo__2] = min(prop_eff_vec_M0_fixed_real_2);
            x_minimo__2 = Tt3_prima_val2(indice_minimo__2);
            [minimo_y__2i, indice_minimo__2i] = min(prop_eff_vec_M0_fixed_reali);
            x_minimo__2i = Tt3_prima_val2(indice_minimo__2i);
            [minimo_y__2no, indice_minimo__2no] = min(prop_eff_vec_M0_fixed_real_no_mod);
            x_minimo__2no = Tt3_prima_val2(indice_minimo__2no);
            plot(app.UIAxes10,Tt3_prima_val2,prop_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,prop_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,prop_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes10,'on');
            plot(app.UIAxes10,x_minimo__2,minimo_y__2,'ko','MarkerSize',5);
            plot(app.UIAxes10,x_minimo__2i,minimo_y__2i,'ro','MarkerSize',5);
            plot(app.UIAxes10,x_minimo__2no,minimo_y__2no,'bo','MarkerSize',5);
            title(app.UIAxes10,'Propulsive efficiency (\eta_{pr}) - T_{t3prima} trends')
            xlabel(app.UIAxes10,'T_{t3} [K]')
            ylabel(app.UIAxes10,'\eta_{pr} [-]')
            hold(app.UIAxes10,'off');
            
            plot(app.UIAxes8,Tt3_prima_val2,C_ts_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,C_ts_vec_M0_fixed_reali,'r--',Tt3_prima_val2,C_ts_vec_M0_fixed_real_no_mod,'b--');
            title(app.UIAxes8,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes8,'T_{t3} [K]')
            ylabel(app.UIAxes8,'C_{TS} [J/Kg]')
            hold(app.UIAxes8);

            [minimo_y_4_2, indice_minimo_4_2] = min(W_vec_M0_fixed_real_2);
            x_minimo_4_2 = Tt3_prima_val2(indice_minimo_4_2);
            [minimo_y_4_2i, indice_minimo_4_2i] = min(W_vec_M0_fixed_reali);
            x_minimo_4_2i = Tt3_prima_val2(indice_minimo_4_2i);
            [minimo_y_4_2no, indice_minimo_4_2no] = min(W_vec_M0_fixed_real_no_mod);
            x_minimo_4_2no = Tt3_prima_val2(indice_minimo_4_2no);
            plot(app.UIAxes11,Tt3_prima_val2,W_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,W_vec_M0_fixed_reali,'r--',Tt3_prima_val2,W_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes11,'on');
            plot(app.UIAxes11,x_minimo_4_2,minimo_y_4_2,'ko','MarkerSize',5);
            plot(app.UIAxes11,x_minimo_4_2i,minimo_y_4_2i,'ro','MarkerSize',5);
            plot(app.UIAxes11,x_minimo_4_2no,minimo_y_4_2no,'bo','MarkerSize',5);
            title(app.UIAxes11,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes11,'T_{t3} [K]')
            ylabel(app.UIAxes11,'W_s [kJ]')
            hold(app.UIAxes11,'off');

            app.ComparisonperformedCheckBox.Value = true;
        end

        % Button pushed function: TurbojetcyclewithintercoolerButton
        function TurbojetcyclewithintercoolerButtonPushed(app, event)
            cla(app.UIAxes, 'reset'); % Clear axes control.
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes2_2, 'reset');
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            e_interp=0;
            diff_T=0;
            Delta_T_Pc_values=0;
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            Tt3_prima_val=0;
            Tt25_p_val=0;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25,Pt25,Tt25_p,Pt25_p,Tt3_prima,Pt3,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_ID(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val,Delta_T_Pc_values,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_p_val);
            
       
            %app.UITable.RowName={'Specific Thrust','C_{TS}','\Delta K_{c}'};


            app.EditField_18.Value=specific_thrust;
            app.EditField_19.Value=C_ts*1000000;
            app.EditField_20.Value=Deltak_c/1000;
            app.W_skJEditField.Value = w_s/1000;
            
            %app.UITable_2.RowName={'Overall efficiency', 'Propulsive efficiency','Thermal efficiency'};
            app.EditField_21.Value=overall_eff;
            app.EditField_22.Value=prop_eff;
            app.EditField_23.Value=thermal_eff;
            app.EditField_24.Value=Tt4;
            app.EditField_25.Value=V0;

            app.T_t25EditField.Value=Tt25;
            app.T_t25EditField_2.Value=Tt25_p;
            app.P_t25EditField.Value=Pt25/1000;
            app.P_t25EditField_2.Value=Pt25_p/1000;
            
            app.EditField.Value = Tt0;
            app.EditField_2.Value  = Pt0/1000;
            app.EditField_3.Value  = pi_fs;
            app.EditField_4.Value  = Tt2;
            app.EditField_5.Value  = Pt2/1000;
            app.EditField_6.Value  = Tt3_prima;
            app.EditField_7.Value  = Pt3/1000;
            app.EditField_8.Value  = pi_c;
            app.EditField_9.Value  = Pt4/1000;
            app.EditField_10.Value  = alpha_b;
            app.EditField_11.Value  = Tt5;
            app.EditField_12.Value  = Pt5/1000;
            app.EditField_13.Value  = alpha_prima;
            app.EditField_14.Value  = T9;
            app.EditField_15.Value  = Pt9/1000;
            app.EditField_16.Value  = pi_9;
            app.EditField_17.Value  = V9;
            M0_values=[M_0-1,M_0];
%             Delta_Tc_values=0:300:900;
            Tt4_values=1800:300:2400;
            Tt3_prima_val=Tt3_prima-200:1:Tt3_prima+1450;
            Delta_T_Pc_Values=0;
            Tt25_p_val = Tt0-390:1:Tt25;

            specific_thrust_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));


            specific_thrust_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            overall_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            C_ts_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            prop_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            thermal_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            W_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            V9_Tt25_p_val=zeros(1,length(Tt25_p_val));
            
            
            specific_thrust_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));

            
            specific_thrust_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));


            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real(i_1)>0,i_1<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_real(i_1),overall_eff_vec_M0_fixed_real(i_1),C_ts_vec_M0_fixed_real(i_1),prop_eff_vec_M0_fixed_real(i_1),thermal_eff_vec_M0_fixed_real(i_1),deltak_c,W_vec_M0_fixed_real(i_1)] = CALCULATION_IC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_1),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0);
                i_1=i_1+1;
                
            end

            i_2=1;
            while or(specific_thrust_vec_M0_fixed_Tt25_p(i_2)>0,i_2<length(Tt25_p_val)) 
                [specific_thrust_vec_M0_fixed_Tt25_p(i_2),overall_eff_vec_M0_fixed_Tt25_p(i_2),C_ts_vec_M0_fixed_Tt25_p(i_2),prop_eff_vec_M0_fixed_Tt25_p(i_2),thermal_eff_vec_M0_fixed_Tt25_p(i_2),deltak_c,W_vec_M0_fixed_Tt25_p(i_2),~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,V9_Tt25_p_val(i_2),~] = CALCULATION_IC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,0,Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_p_val(i_2));
                i_2=i_2+1;
                
            end


            i_3=1;
            while or(specific_thrust_vec_M0_fixed_theta4down(i_3)>0,i_3<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_theta4down(i_3),overall_eff_vec_M0_fixed_theta4down(i_3),C_ts_vec_M0_fixed_theta4down(i_3),prop_eff_vec_M0_fixed_theta4down(i_3),thermal_eff_vec_M0_fixed_theta4down(i_3),deltak_c,W_vec_M0_fixed_theta4down(i_3)] = CALCULATION_IC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_3),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(1),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0);
                i_3=i_3+1;
                
            end


            i_5=1;
            while or(specific_thrust_vec_M0_fixed_theta0down(i_5)>0 ,i_5<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_theta0down(i_5),overall_eff_vec_M0_fixed_theta0down(i_5),C_ts_vec_M0_fixed_theta0down(i_5),prop_eff_vec_M0_fixed_theta0down(i_5),thermal_eff_vec_M0_fixed_theta0down(i_5),deltak_c,W_vec_M0_fixed_theta0down(i_5)] = CALCULATION_IC_ID(M0_values(1),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_5),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0);
                i_5=i_5+1;
                
            end


            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;r25=0;r26=0;r27=0;r28=0;
            for j=1:length(Tt25_p_val)
                if j>=length(Tt25_p_val)
                    specific_thrust_vec_M0_fixed_Tt25_p(j)=NaN;
                    W_vec_M0_fixed_Tt25_p(j)=NaN;
                end
            end

            for i=1:length(prop_eff_vec_M0_fixed_real)
                
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r21==1)
                    if r21==0
                        specific_thrust_vec_M0_fixed_real(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r21=1;
                    else
                        specific_thrust_vec_M0_fixed_real(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r23==1)
                    if r23==0
                        specific_thrust_vec_M0_fixed_theta4down(i)=0;
    %                     W_vec_M0_fixed_theta4down(i)=W_vec_M0_fixed_theta4down(i-1);
                        r23=1;
                    else
                        specific_thrust_vec_M0_fixed_theta4down(i)=NaN;
    %                     W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
            
            
                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r25==1)
                    if r25==0
                        specific_thrust_vec_M0_fixed_theta0down(i)=0;
    %                     W_vec_M0_fixed_theta0down(i)=W_vec_M0_fixed_theta0down(i-1);
                        r25=1;
                    else
                        specific_thrust_vec_M0_fixed_theta0down(i)=NaN;
    %                     W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)<=0,r17==1)
                    if r17==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=0;
                        r17=1;
                    else
                        thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)>=1,r3==1)
                    if r3==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=1;
                        r3=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta0down(i)<=0,r18==1)
                    if r18==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=0;
                        r18=1;
                    else
                        thermal_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
       
                if or(thermal_eff_vec_M0_fixed_theta0down(i)>=1,r5==1)
                    if r5==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=1;
                        r5=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end

                if or(C_ts_vec_M0_fixed_real(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real(i)=NaN;
                    r4=1;
                end
    
                if or(C_ts_vec_M0_fixed_theta4down(i)<=0,r13==0)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                    r13=1;
                end
                
                if Tt3_prima_val(i)>= Tt4_values(1)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                end

                if or(C_ts_vec_M0_fixed_theta0down(i)<=0,r14==0)
                    C_ts_vec_M0_fixed_theta0down(i)=NaN;
                    r14=1;
                end

                if or(prop_eff_vec_M0_fixed_real(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta4down(i)>=1,r15==1)
                    if r15==0
                        prop_eff_vec_M0_fixed_theta4down(i)=1;
                        r15=1;
                    else
                        prop_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta0down(i)>=1,r16==1)
                    if r16==0
                        prop_eff_vec_M0_fixed_theta0down(i)=1;
                        r16=1;
                    else
                        prop_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
    
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real(i)=1;
                        r6=1;
%                     else
%                         overall_eff_vec_M0_fixed_real(i)=1;
                    end
                end
                
                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r19==1)
                    if r19==0
                        overall_eff_vec_M0_fixed_theta4down(i)=0;
                        r19=1;
                    else
                        overall_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta4down(i)>=1,r8==1)
                    if r8==0
                        overall_eff_vec_M0_fixed_theta4down(i)=1;
                        r8=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta4down(i)=1;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r20==1)
                    if r20==0
                        overall_eff_vec_M0_fixed_theta0down(i)=0;
                        r20=1;
                    else
                        overall_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta0down(i)>=1,r9==1)
                    if r9==0
                        overall_eff_vec_M0_fixed_theta0down(i)=1;
                        r9=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end


                if or( W_vec_M0_fixed_real(i)>=0,r26==1)
                    if r26==0
                        W_vec_M0_fixed_real(i)=0;
                        r26=1;
                    else
                        W_vec_M0_fixed_real(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta4down(i)>=0,r27==1)
                    if r27==0
                        W_vec_M0_fixed_theta4down(i)=0;
                        r27=1;
                    else
                        W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta0down(i)>=0,r28==1)
                    if r28==0
                        W_vec_M0_fixed_theta0down(i)=0;
                        r28=1;
                    else
                        W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
                
            end


            [maximo_y, indice_maximo] = max(specific_thrust_vec_M0_fixed_real);
            x_maximo = Tt3_prima_val(indice_maximo);

            [ymax4down, indice_4down] = max(specific_thrust_vec_M0_fixed_theta4down);
            xmax4down = Tt3_prima_val(indice_4down);

            [ymax0down, indice_0down] = max(specific_thrust_vec_M0_fixed_theta0down);
            xmax0down = Tt3_prima_val(indice_0down);
            plot(app.UIAxes,Tt3_prima_val,specific_thrust_vec_M0_fixed_real,'k-',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes,'on');
            plot(app.UIAxes,x_maximo,maximo_y,'ko','MarkerSize',5);

            plot(app.UIAxes,xmax4down,ymax4down,'ro','MarkerSize',5);

            plot(app.UIAxes,xmax0down,ymax0down,'bo','MarkerSize',5);
            legend(app.UIAxes,'Real','\theta_4 down','\theta_0 down');
            title(app.UIAxes,'Specific thrust (\psi) - T_{t3p} trends');
            xlabel(app.UIAxes,'T_{t3p} [K]');
            ylabel(app.UIAxes,'\psi [m/s]');
            hold(app.UIAxes,'off');

            [maximo_y_2, indice_maximo_2] = max(overall_eff_vec_M0_fixed_real);
            x_maximo_2 = Tt3_prima_val(indice_maximo_2);

            [ymax4down_2, indice_4down_2] = max(overall_eff_vec_M0_fixed_theta4down);
            xmax4down_2 = Tt3_prima_val(indice_4down_2);

            [ymax0down_2, indice_0down_2] = max(overall_eff_vec_M0_fixed_theta0down);
            xmax0down_2 = Tt3_prima_val(indice_0down_2);
            plot(app.UIAxes3,Tt3_prima_val,overall_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,overall_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,overall_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,x_maximo_2,maximo_y_2,'ko','MarkerSize',5);

            plot(app.UIAxes3,xmax4down_2,ymax4down_2,'ro','MarkerSize',5);

            plot(app.UIAxes3,xmax0down_2,ymax0down_2,'bo','MarkerSize',5);
            title(app.UIAxes3,'Overall efficiency (\eta_{O}) - T_{t3p} trends');
            xlabel(app.UIAxes3,'T_{t3p} [K]');
            ylabel(app.UIAxes3,'\eta_{O} [-]');
            hold(app.UIAxes3,'off');

            [maximo_y_3, indice_maximo_3] = max(thermal_eff_vec_M0_fixed_real);
            x_maximo_3 = Tt3_prima_val(indice_maximo_3);

            [ymax4down_3, indice_4down_3] = max(thermal_eff_vec_M0_fixed_theta4down);
            xmax4down_3 = Tt3_prima_val(indice_4down_3);

            [ymax0down_3, indice_0down_3] = max(thermal_eff_vec_M0_fixed_theta0down);
            xmax0down_3 = Tt3_prima_val(indice_0down_3);
            plot(app.UIAxes2,Tt3_prima_val,thermal_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,x_maximo_3,maximo_y_3,'ko','MarkerSize',5);

            plot(app.UIAxes2,xmax4down_3,ymax4down_3,'ro','MarkerSize',5);

            plot(app.UIAxes2,xmax0down_3,ymax0down_3,'bo','MarkerSize',5);
            title(app.UIAxes2,'Thermal efficiency (\eta_{th}) - T_{t3p} trends')
            xlabel(app.UIAxes2,'T_{t3p} [K]')
            ylabel(app.UIAxes2,'\eta_{th} [-]')
            hold(app.UIAxes2,'off');

            [minimo_y, indice_minimo] = min(prop_eff_vec_M0_fixed_real);
            x_minimo = Tt3_prima_val(indice_minimo);

            [ymin4down, indice_4down_4] = min(prop_eff_vec_M0_fixed_theta4down);
            xmin4down = Tt3_prima_val(indice_4down_4);

            [ymin0down, indice_0down_4] = min(prop_eff_vec_M0_fixed_theta0down);
            xmin0down = Tt3_prima_val(indice_0down_4);
            plot(app.UIAxes4,Tt3_prima_val,prop_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,prop_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,prop_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,x_minimo,minimo_y,'ko','MarkerSize',5);

            plot(app.UIAxes4,xmin4down,ymin4down,'ro','MarkerSize',5);

            plot(app.UIAxes4,xmin0down,ymin0down,'bo','MarkerSize',5);
            title(app.UIAxes4,'Propulsive efficiency (\eta_{pr}) - T_{t3} trends')
            xlabel(app.UIAxes4,'T_{t3} [K]')
            ylabel(app.UIAxes4,'\eta_{pr} [-]')
            hold(app.UIAxes4,'off');
            
            plot(app.UIAxes2_2,Tt3_prima_val,C_ts_vec_M0_fixed_real,'k-',Tt3_prima_val,C_ts_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,C_ts_vec_M0_fixed_theta0down,'b--');

            title(app.UIAxes2_2,'CTS - T_{t3} trends')
            xlabel(app.UIAxes2_2,'T_{t3} [K]')
            ylabel(app.UIAxes2_2,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_2);

            [minimo_y_4, indice_minimo_4] = min(W_vec_M0_fixed_real);
            x_minimo_4 = Tt3_prima_val(indice_minimo_4);
            [ymin4down_2, indice_4down_4_2] = min(W_vec_M0_fixed_theta4down);
            xmin4down_2 = Tt3_prima_val(indice_4down_4_2);
            [ymin0down_2, indice_0down_4_2] = min(W_vec_M0_fixed_theta0down);
            xmin0down_2 = Tt3_prima_val(indice_0down_4_2);

            plot(app.UIAxes5,Tt3_prima_val,W_vec_M0_fixed_real,'k-',Tt3_prima_val,W_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,W_vec_M0_fixed_theta0down,'b--');
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,x_minimo_4,minimo_y_4,'ko','MarkerSize',5);
            plot(app.UIAxes5,xmin4down_2,ymin4down_2,'ro','MarkerSize',5);
            plot(app.UIAxes5,xmin0down_2,ymin0down_2,'bo','MarkerSize',5);
            title(app.UIAxes5,'Work specific output - T_{t3} trends')
            xlabel(app.UIAxes5,'T_{t3} [K]')
            ylabel(app.UIAxes5,'W_s [kJ]')
            hold(app.UIAxes5,'off');

            %% Tt2.5' decision

            [maximo_Tt25p_y, indice_maximo_Tt25p] = max(specific_thrust_vec_M0_fixed_Tt25_p);
            x_maximo_Tt25_p = Tt25_p_val(indice_maximo_Tt25p);
            plot(app.UIAxes12,Tt25_p_val,specific_thrust_vec_M0_fixed_Tt25_p,'k-')
            hold(app.UIAxes12,'on')
            plot(app.UIAxes12,x_maximo_Tt25_p,maximo_Tt25p_y,'ko','MarkerSize',5);
            title(app.UIAxes12,'Specific thrust vs T_{t25_p}')
            xlabel(app.UIAxes12,'T_{t25_p} [K]')
            ylabel(app.UIAxes12,'\psi [m/s]')
            hold(app.UIAxes12,'off');

            [minimo_Tt25p_y, indice_minimo_Tt25p] = min(W_vec_M0_fixed_Tt25_p);
            x_minimo_Tt25_p = Tt25_p_val(indice_minimo_Tt25p);
            plot(app.UIAxes13,Tt25_p_val,W_vec_M0_fixed_Tt25_p,'k-')
            hold(app.UIAxes13,'on')
            plot(app.UIAxes13,x_minimo_Tt25_p,minimo_Tt25p_y,'ko','MarkerSize',5);
            title(app.UIAxes13,'Specific work output vs T_{t25_p}')
            xlabel(app.UIAxes13,'T_{t25_p} [K]')
            ylabel(app.UIAxes13,'W_s [kJ]')
            hold(app.UIAxes13,'off');

            app.SimulationPerformedIdealCheckBox.Value = true;
        end

        % Button pushed function: TurbojetcyclewithidealprecoolerButton
        function TurbojetcyclewithidealprecoolerButtonPushed(app, event)
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            M_2=app.M_2EditField_2.Value;
            tau_c =app.tau_cEditField.Value;
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            Tt3_prima_val=0;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt3_prima,Pt3,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c,0,0);
       
            %app.UITable.RowName={'Specific Thrust','C_{TS}','\Delta K_{c}'};


            app.EditField_51.Value=specific_thrust;
            app.EditField_52.Value=C_ts*1000000;
            app.EditField_53.Value=Deltak_c/1000;
            app.WkJEditField_2.Value = w_s/1000;
            
            %app.UITable_2.RowName={'Overall efficiency', 'Propulsive efficiency','Thermal efficiency'};
            app.EditField_54.Value=overall_eff;
            app.EditField_55.Value=prop_eff;
            app.EditField_56.Value=thermal_eff;
            app.EditField_57.Value=Tt4;
            app.EditField_58.Value=V0;

            
            app.EditField_34.Value = Tt0;
            app.EditField_35.Value  = Pt0/1000;
            app.EditField_36.Value  = pi_fs;
            app.EditField_37.Value  = Tt2;
            app.EditField_38.Value  = Pt2/1000;
            app.EditField_39.Value  = Tt3_prima;
            app.EditField_40.Value  = Pt3/1000;
            app.EditField_41.Value  = pi_c;
            app.EditField_42.Value  = Pt4/1000;
            app.EditField_43.Value  = alpha_b;
            app.EditField_44.Value  = Tt5;
            app.EditField_45.Value  = Pt5/1000;
            app.EditField_46.Value  = alpha_prima;
            app.EditField_47.Value  = T9;
            app.EditField_48.Value  = Pt9/1000;
            app.EditField_49.Value  = pi_9;
            app.EditField_50.Value  = V9;
            app.M_2EditField.Value = M_2;
            

            Tt4_values=1900:200:2300;
            Tt3_prima_val=Tt2:1:Tt3_prima+800;
            

            specific_thrust_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));

            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real(i_1)>0,i_1<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_real(i_1),overall_eff_vec_M0_fixed_real(i_1),C_ts_vec_M0_fixed_real(i_1),prop_eff_vec_M0_fixed_real(i_1),thermal_eff_vec_M0_fixed_real(i_1),deltak_c,W_vec_M0_fixed_real(i_1)] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c,0,0);
                i_1=i_1+1;
                
            end
            M_2_val = 1:0.01:M_0;
            specific_thrust_vec_M2_val=zeros(1,length(M_2_val));
            overall_eff_vec_M2_val=zeros(1,length(M_2_val));
            C_ts_vec_M2_val=zeros(1,length(M_2_val));
            prop_eff_vec_M2_val=zeros(1,length(M_2_val));
            thermal_eff_vec_M2_val=zeros(1,length(M_2_val));
            W_vec_M2_val=zeros(1,length(M_2_val));
            Tt3_prima_M_2=zeros(1,length(M_2_val));

            i_2=1;
            while or(specific_thrust_vec_M2_val(i_2)>0,i_2<length(M_2_val)) 
                [specific_thrust_vec_M2_val(i_2),overall_eff_vec_M2_val(i_2),C_ts_vec_M2_val(i_2),prop_eff_vec_M2_val(i_2),thermal_eff_vec_M2_val(i_2),deltak_c,W_vec_M2_val(i_2),~,~,~,~,~,Tt3_prima_M_2(i_2)] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,0,eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2_val(i_2),tau_c,0,0);
                i_2=i_2+1;
                
            end

            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;r25=0;r26=0;r27=0;r28=0;


            for i=1:length(prop_eff_vec_M0_fixed_real)
                
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r3==1)
                    if r3==0
                        specific_thrust_vec_M0_fixed_real(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r3=1;
                    else
                        specific_thrust_vec_M0_fixed_real(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real(i)=NaN;
                    r4=1;
                end
                if Tt3_prima_val(i)>=Tt4
                    C_ts_vec_M0_fixed_real(i)=NaN;
                end

                if or(prop_eff_vec_M0_fixed_real(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

    
                if or(overall_eff_vec_M0_fixed_real(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real(i)=1;
                        r6=1;
%                     else
%                         overall_eff_vec_M0_fixed_real(i)=1;
                    end
                end

                 if or( W_vec_M0_fixed_real(i)>=0,r26==1)
                    if r26==0
                        W_vec_M0_fixed_real(i)=0;
                        r26=1;
                    else
                        W_vec_M0_fixed_real(i)=NaN;
                    end
                end
                
            end

            for j=1:length(prop_eff_vec_M2_val)
                
                if or(specific_thrust_vec_M2_val(j)<=0,r5==1)
                    if r5==0
                        specific_thrust_vec_M2_val(j)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r5=1;
                    else
                        specific_thrust_vec_M2_val(j)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M2_val(j)<=0,r8==1)
                    if r8==0
                        thermal_eff_vec_M2_val(j)=0;
                        r8=1;
                    else
                        thermal_eff_vec_M2_val(j)=NaN;
                    end
                end

                if or(thermal_eff_vec_M2_val(j)>=1,r9==1)
                    thermal_eff_vec_M2_val(j)=1;
                end

                if j>1
                    if or (thermal_eff_vec_M2_val(j)>=1 && thermal_eff_vec_M2_val(j-1)>=1,thermal_eff_vec_M2_val(j)>=1 && isnan(thermal_eff_vec_M2_val(j-1)))
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if thermal_eff_vec_M2_val(j)>=1
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                end
                if or(C_ts_vec_M2_val(j)<=0,r11==0)
                    C_ts_vec_M2_val(j)=NaN;
                    r11=1;
                end

                if Tt3_prima_M_2(j)>=Tt4 && prop_eff_vec_M2_val(j)>=0
                    C_ts_vec_M2_val(j)=NaN;
                end

                if or(prop_eff_vec_M2_val(j)>=1,r12==1)
                    if r12==0
                        prop_eff_vec_M2_val(j)=1;
                        r12=1;
                    else
                        prop_eff_vec_M2_val(j)=NaN;
                    end
                end

    
                if or(overall_eff_vec_M2_val(j)<=0,r13==1)
                    if r13==0
                        overall_eff_vec_M2_val(j)=0;
                        r13=1;
                    else
                        overall_eff_vec_M2_val(j)=NaN;
                    end
                end

                if j>1
                    if or (overall_eff_vec_M2_val(j)>=1 && overall_eff_vec_M2_val(j-1)>=1,overall_eff_vec_M2_val(j)>=1 && isnan(overall_eff_vec_M2_val(j-1)))
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if overall_eff_vec_M2_val(j)>=1
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                end

                if or( W_vec_M2_val(j)>=0,r27==1)
                    if r27==0
                        W_vec_M2_val(j)=0;
                        r27=1;
                    else
                        W_vec_M2_val(j)=NaN;
                    end
                end
                
            end
            
 %% GRAPHS TT3'
            [maximo_y, indice_maximo] = max(specific_thrust_vec_M0_fixed_real);
            x_maximo = Tt3_prima_val(indice_maximo);

            plot(app.UIAxes_2,Tt3_prima_val,specific_thrust_vec_M0_fixed_real,'k-');

            hold(app.UIAxes_2,'on');
            plot(app.UIAxes_2,x_maximo,maximo_y,'ko','MarkerSize',5);
            legend(app.UIAxes_2,'Real');
            title(app.UIAxes_2,'Specific thrust (\psi) - T_{t3prima} trends');
            xlabel(app.UIAxes_2,'T_{t3prima} [K]');
            ylabel(app.UIAxes_2,'\psi [m/s]');
            hold(app.UIAxes_2,'off');

            [maximo_y_2, indice_maximo_2] = max(overall_eff_vec_M0_fixed_real);
            x_maximo_2 = Tt3_prima_val(indice_maximo_2);

            plot(app.UIAxes3_2,Tt3_prima_val,overall_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes3_2,'on');
            plot(app.UIAxes3_2,x_maximo_2,maximo_y_2,'ko','MarkerSize',5);
            title(app.UIAxes3_2,'Overall efficiency (\eta_{O}) - T_{t3prima} trends');
            xlabel(app.UIAxes3_2,'T_{t3prima} [K]');
            ylabel(app.UIAxes3_2,'\eta_{O} [-]');
            hold(app.UIAxes3_2,'off');

            [maximo_y_3, indice_maximo_3] = max(thermal_eff_vec_M0_fixed_real);
            x_maximo_3 = Tt3_prima_val(indice_maximo_3);
            plot(app.UIAxes2_3,Tt3_prima_val,thermal_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes2_3,'on');
            plot(app.UIAxes2_3,x_maximo_3,maximo_y_3,'ko','MarkerSize',5);
            title(app.UIAxes2_3,'Thermal efficiency (\eta_{th}) - T_{t3prima} trends')
            xlabel(app.UIAxes2_3,'T_{t3prima} [K]')
            ylabel(app.UIAxes2_3,'\eta_{th} [-]')
            hold(app.UIAxes2_3,'off');

            [minimo_y, indice_minimo] = min(prop_eff_vec_M0_fixed_real);
            x_minimo = Tt3_prima_val(indice_minimo);
            plot(app.UIAxes4_2,Tt3_prima_val,prop_eff_vec_M0_fixed_real,'k-');

            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2,x_minimo,minimo_y,'ko','MarkerSize',5);
            title(app.UIAxes4_2,'Propulsive efficiency (\eta_{pr}) - T_{t3prima} trends')
            xlabel(app.UIAxes4_2,'T_{t3prima} [K]')
            ylabel(app.UIAxes4_2,'\eta_{pr} [-]')
            hold(app.UIAxes4_2,'off');
            
            plot(app.UIAxes2_4,Tt3_prima_val,C_ts_vec_M0_fixed_real,'k-');
            title(app.UIAxes2_4,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes2_4,'T_{t3prima} [K]')
            ylabel(app.UIAxes2_4,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_4);

            [minimo_y_4, indice_minimo_4] = min(W_vec_M0_fixed_real);
            x_minimo_4 = Tt3_prima_val(indice_minimo_4);
            plot(app.UIAxes5_2,Tt3_prima_val,W_vec_M0_fixed_real,'k-');
            hold(app.UIAxes5_2,'on');
            plot(app.UIAxes5_2,x_minimo_4,minimo_y_4,'ko','MarkerSize',5);
            title(app.UIAxes5_2,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes5_2,'T_{t3prima} [K]')
            ylabel(app.UIAxes5_2,'W_s [kJ]')
            hold(app.UIAxes5_2,'off');

%% GRAPHS M_2

            [maximo_yM2, indice_maximoM2] = max(specific_thrust_vec_M2_val);
            x_maximoM2 = M_2_val(indice_maximoM2);

            plot(app.UIAxes_3,M_2_val,specific_thrust_vec_M2_val,'k-');
            hold(app.UIAxes_3,'on');
            plot(app.UIAxes_3,x_maximoM2,maximo_yM2,'ko','MarkerSize',5);
            legend(app.UIAxes_3,'Real');
            title(app.UIAxes_3,'Specific thrust (\psi) - M_2 trends');
            xlabel(app.UIAxes_3,'M_2 [-]');
            ylabel(app.UIAxes_3,'\psi [m/s]');
            hold(app.UIAxes_3,'off');

            [maximo_y_2M2, indice_maximo_2M2] = max(overall_eff_vec_M2_val);
            x_maximo_2M2 = M_2_val(indice_maximo_2M2);
            plot(app.UIAxes3_3,M_2_val,overall_eff_vec_M2_val,'k-');
            hold(app.UIAxes3_3,'on');
            plot(app.UIAxes3_3,x_maximo_2M2,maximo_y_2M2,'ko','MarkerSize',5);
            title(app.UIAxes3_3,'Overall efficiency (\eta_{O}) - M_2 trends');
            xlabel(app.UIAxes3_3,'M_2 [-]');
            ylabel(app.UIAxes3_3,'\eta_{O} [-]');
            hold(app.UIAxes3_3,'off');

            [maximo_y_3M2, indice_maximo_3M2] = max(thermal_eff_vec_M2_val);
            x_maximo_3M2 = M_2_val(indice_maximo_3M2);
            plot(app.UIAxes2_5,M_2_val,thermal_eff_vec_M2_val,'k-');
            hold(app.UIAxes2_5,'on');
            plot(app.UIAxes2_5,x_maximo_3M2,maximo_y_3M2,'ko','MarkerSize',5);
            title(app.UIAxes2_5,'Thermal efficiency (\eta_{th}) - M_2 trends')
            xlabel(app.UIAxes2_5,'M_2 [-]')
            ylabel(app.UIAxes2_5,'\eta_{th} [-]')
            hold(app.UIAxes2_5,'off');

            [minimo_yM2, indice_minimoM2] = min(prop_eff_vec_M2_val);
            x_minimoM2 = M_2_val(indice_minimoM2);
            plot(app.UIAxes4_3,M_2_val,prop_eff_vec_M2_val,'k-');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3,x_minimoM2,minimo_yM2,'ko','MarkerSize',5);
            title(app.UIAxes4_3,'Propulsive efficiency (\eta_{pr}) - M_2 trends')
            xlabel(app.UIAxes4_3,'M_2 [-]')
            ylabel(app.UIAxes4_3,'\eta_{pr} [-]')
            hold(app.UIAxes4_3,'off');
            
            plot(app.UIAxes2_6,M_2_val,C_ts_vec_M2_val,'k-');
            title(app.UIAxes2_6,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes2_6,'M_2 [-]')
            ylabel(app.UIAxes2_6,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_6);

            [minimo_y_4M2, indice_minimo_4M2] = min(W_vec_M2_val);
            x_minimo_4M2 = M_2_val(indice_minimo_4M2);
            plot(app.UIAxes5_3,M_2_val,W_vec_M2_val,'k-');
            hold(app.UIAxes5_3,'on');
            plot(app.UIAxes5_3,x_minimo_4M2,minimo_y_4M2,'ko','MarkerSize',5);
            title(app.UIAxes5_3,'Work specific output - M_2 trends')
            xlabel(app.UIAxes5_3,'M_2 [-]')
            ylabel(app.UIAxes5_3,'W_s [kJ]')
            hold(app.UIAxes5_3,'off');

%% FINISHED
            app.SimulationperformedCheckBox_2.Value= true;
        end

        % Button pushed function: TurbojetcyclewithrealprecoolerButton
        function TurbojetcyclewithrealprecoolerButtonPushed(app, event)
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;
            e_prep=app.e_preEditField.Value;
            diff_T=app.DeltaT_preEditField.Value;
            M_2 = app.M_2EditField_2.Value;
            tau_c=app.tau_cEditField.Value;
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt3_prima,Pt3,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,0,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c,0,0);

            Tt3_prima_val2= Tt3_prima-380:1:Tt3_prima+800;
            specific_thrust_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_real_2=zeros(1,length(Tt3_prima_val2));

            specific_thrust_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_reali=zeros(1,length(Tt3_prima_val2));

            specific_thrust_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            overall_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            C_ts_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            prop_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            thermal_eff_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            W_vec_M0_fixed_real_no_mod=zeros(1,length(Tt3_prima_val2));
            
            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real_2(i_1)>0,i_1<length(Tt3_prima_val2)) 
                [specific_thrust_vec_M0_fixed_real_2(i_1),overall_eff_vec_M0_fixed_real_2(i_1),C_ts_vec_M0_fixed_real_2(i_1),prop_eff_vec_M0_fixed_real_2(i_1),thermal_eff_vec_M0_fixed_real_2(i_1),deltak_c,W_vec_M0_fixed_real_2(i_1)] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_1),eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c,diff_T,e_prep);
                i_1=i_1+1;
            end
            i_2=1;
            while or(specific_thrust_vec_M0_fixed_reali(i_2)>0,i_2<length(Tt3_prima_val2)) 
                [specific_thrust_vec_M0_fixed_reali(i_2),overall_eff_vec_M0_fixed_reali(i_2),C_ts_vec_M0_fixed_reali(i_2),prop_eff_vec_M0_fixed_reali(i_2),thermal_eff_vec_M0_fixed_reali(i_2),deltak_c,W_vec_M0_fixed_reali(i_2)] = CALCULATION_PC(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_2),eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,M_2,tau_c,0,0);
                i_2=i_2+1; 
            end
            
            i_3=1;
            while or(specific_thrust_vec_M0_fixed_real_no_mod(i_3)>0,i_3<length(Tt3_prima_val2))
                [specific_thrust_vec_M0_fixed_real_no_mod(i_3),overall_eff_vec_M0_fixed_real_no_mod(i_3),C_ts_vec_M0_fixed_real_no_mod(i_3),prop_eff_vec_M0_fixed_real_no_mod(i_3),thermal_eff_vec_M0_fixed_real_no_mod(i_3),deltak_c,W_vec_M0_fixed_real_no_mod(i_3)] = CALCULATION(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val2(i_3),eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n);
                i_3=i_3+1;
            end


            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;
            for i=1:length(prop_eff_vec_M0_fixed_real_2)
                
                if or(specific_thrust_vec_M0_fixed_real_2(i)<=0,r21==1)
                    if r21==0
                        specific_thrust_vec_M0_fixed_real_2(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r21=1;
                    else
                        specific_thrust_vec_M0_fixed_real_2(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real_2(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real_2(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real_2(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real_2(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real_2(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real_2(i)=NaN;
                    r4=1;
                end
    
                
                if or(prop_eff_vec_M0_fixed_real_2(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real_2(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end


                if or(specific_thrust_vec_M0_fixed_real_2(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real_2(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real_2(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real_2(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real_2(i)=1;
                        r6=1;
                    else
                        overall_eff_vec_M0_fixed_real_2(i)=1;
                    end
                end

                if or( W_vec_M0_fixed_real_2(i)>=0,r15==1)
                    if r15==0
                        W_vec_M0_fixed_real_2(i)=0;
                        r15=1;
                    else
                        W_vec_M0_fixed_real_2(i)=NaN;
                    end
                end
                
            end

            for j=1:length(prop_eff_vec_M0_fixed_reali)
                
                if or(specific_thrust_vec_M0_fixed_reali(j)<=0,r3==1)
                    if r3==0
                        specific_thrust_vec_M0_fixed_reali(j)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r3=1;
                    else
                        specific_thrust_vec_M0_fixed_reali(j)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_reali(j)<=0,r5==1)
                    if r5==0
                        thermal_eff_vec_M0_fixed_reali(j)=0;
                        r5=1;
                    else
                        thermal_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_reali(j)>=1,r8==1)
                    if r8==0
                        thermal_eff_vec_M0_fixed_reali(j)=1;
                        r8=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_reali(j)<=0,r9==0)
                    C_ts_vec_M0_fixed_reali(j)=NaN;
                    r9=1;
                end
                if Tt3_prima_val2(j)>=Tt4
                    C_ts_vec_M0_fixed_reali(j)=NaN;
                end
                
                if or(prop_eff_vec_M0_fixed_reali(j)>=1,r11==1)
                    if r11==0
                        prop_eff_vec_M0_fixed_reali(j)=1;
                        r11=1;
                    else
                        prop_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end


                if or(specific_thrust_vec_M0_fixed_reali(j)<=0,r12==1)
                    if r12==0
                        overall_eff_vec_M0_fixed_reali(j)=0;
                        r12=1;
                    else
                        overall_eff_vec_M0_fixed_reali(j)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_reali(j)>=1,r13==1)
                    if r13==0
                        overall_eff_vec_M0_fixed_reali(j)=1;
                        r13=1;
                    else
                        overall_eff_vec_M0_fixed_reali(j)=1;
                    end
                end

                if or( W_vec_M0_fixed_reali(j)>=0,r14==1)
                    if r14==0
                        W_vec_M0_fixed_reali(j)=0;
                        r14=1;
                    else
                        W_vec_M0_fixed_reali(j)=NaN;
                    end
                end
                
            end

            for k=1:length(prop_eff_vec_M0_fixed_real_no_mod)
                
                if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r16==1)
                    if r16==0
                        specific_thrust_vec_M0_fixed_real_no_mod(k)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r16=1;
                    else
                        specific_thrust_vec_M0_fixed_real_no_mod(k)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M0_fixed_real_no_mod(k)<=0,r17==1)
                    if r17==0
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=0;
                        r17=1;
                    else
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real_no_mod(k)>=1,r18==1)
                    if r18==0
                        thermal_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r18=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(C_ts_vec_M0_fixed_real_no_mod(k)<=0,r19==0)
                    C_ts_vec_M0_fixed_real_no_mod(k)=NaN;
                    r19=1;
                end
    
                
                if or(prop_eff_vec_M0_fixed_real_no_mod(k)>=1,r20==1)
                    if r20==0
                        prop_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r20=1;
                    else
                        prop_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end


%                 if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r21==1)
%                     if r21==0
%                         specific_thrust_vec_M0_fixed_real_no_mod(k)=0;
%                         r21=1;
%                     else
%                         specific_thrust_vec_M0_fixed_real_no_mod(k)=NaN;
%                     end
%                 end

                if or(overall_eff_vec_M0_fixed_real_no_mod(k)>=1,r22==1)
                    if r22==0
                        overall_eff_vec_M0_fixed_real_no_mod(k)=1;
                        r22=1;
%                     else
%                         overall_eff_vec_M0_fixed_real_no_mod(k)=1;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_real_no_mod(k)<=0,r24==1)
                    if r24==0
                        overall_eff_vec_M0_fixed_real_no_mod(k)=0;
                        r24=1;
                    else
                        overall_eff_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end
                
                if or( W_vec_M0_fixed_real_no_mod(k)>=0,r23==1)
                    if r23==0
                        W_vec_M0_fixed_real_no_mod(k)=0;
                        r23=1;
                    else
                        W_vec_M0_fixed_real_no_mod(k)=NaN;
                    end
                end
                
            end
            

            [maximo_y_1, indice_maximo_1] = max(specific_thrust_vec_M0_fixed_real_2);
            x_maximo = Tt3_prima_val2(indice_maximo_1);
            [maximo_y_1i, indice_maximo_1i] = max(specific_thrust_vec_M0_fixed_reali);
            x_maximoi = Tt3_prima_val2(indice_maximo_1i);
            [maximo_y_1_no_mod, indice_maximo_1_no_mod] = max(specific_thrust_vec_M0_fixed_real_no_mod);
            x_maximo_no_mod = Tt3_prima_val2(indice_maximo_1_no_mod);

            plot(app.UIAxes6_2,Tt3_prima_val2,specific_thrust_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,specific_thrust_vec_M0_fixed_reali,'r--',Tt3_prima_val2,specific_thrust_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes6_2,'on');
            plot(app.UIAxes6_2,x_maximo,maximo_y_1,'ko','MarkerSize',5);
            plot(app.UIAxes6_2,x_maximoi,maximo_y_1i,'ro','MarkerSize',5);
            plot(app.UIAxes6_2,x_maximo_no_mod,maximo_y_1_no_mod,'bo','MarkerSize',5);
            legend(app.UIAxes6_2,'Real pre-cooler','Ideal pre-cooler','No pre-cooler');
            title(app.UIAxes6_2,'Specific thrust (\psi) - T_{t3prima} trends');
            xlabel(app.UIAxes6_2,'T_{t3} [K]');
            ylabel(app.UIAxes6_2,'\psi [m/s]');
            hold(app.UIAxes6_2,'off');


            [maximo_y_2_2, indice_maximo_2_2] = max(overall_eff_vec_M0_fixed_real_2);
            x_maximo_2_2 = Tt3_prima_val2(indice_maximo_2_2);
            [maximo_y_2_2i, indice_maximo_2_2i] = max(overall_eff_vec_M0_fixed_reali);
            x_maximo_2_2i = Tt3_prima_val2(indice_maximo_2_2i);
            [maximo_y_2_2_no_mod, indice_maximo_2_2_no_mod] = max(overall_eff_vec_M0_fixed_real_no_mod);
            x_maximo_2_2_no_mod = Tt3_prima_val2(indice_maximo_2_2_no_mod);
            plot(app.UIAxes9_2,Tt3_prima_val2,overall_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,overall_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,overall_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes9_2,'on');
            plot(app.UIAxes9_2,x_maximo_2_2,maximo_y_2_2,'ko','MarkerSize',5);
            plot(app.UIAxes9_2,x_maximo_2_2i,maximo_y_2_2i,'ro','MarkerSize',5);
            plot(app.UIAxes9_2,x_maximo_2_2_no_mod,maximo_y_2_2_no_mod,'bo','MarkerSize',5);
            title(app.UIAxes9_2,'Overall efficiency (\eta_{O}) - T_{t3prima} trends');
            xlabel(app.UIAxes9_2,'T_{t3} [K]');
            ylabel(app.UIAxes9_2,'\eta_{O} [-]');
            hold(app.UIAxes9_2,'off');

            [maximo_y_3_2, indice_maximo_3_2] = max(thermal_eff_vec_M0_fixed_real_2);
            x_maximo_3_2 = Tt3_prima_val2(indice_maximo_3_2);
            [maximo_y_3_2i, indice_maximo_3_2i] = max(thermal_eff_vec_M0_fixed_reali);
            x_maximo_3_2i = Tt3_prima_val2(indice_maximo_3_2i);
            [maximo_y_3_2_no_mod, indice_maximo_3_2_no_mod] = max(thermal_eff_vec_M0_fixed_real_no_mod);
            x_maximo_3_2_no_mod = Tt3_prima_val2(indice_maximo_3_2_no_mod);
            plot(app.UIAxes7_2,Tt3_prima_val2,thermal_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,thermal_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,thermal_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes7_2,'on');
            plot(app.UIAxes7_2,x_maximo_3_2,maximo_y_3_2,'ko','MarkerSize',5);
            plot(app.UIAxes7_2,x_maximo_3_2i,maximo_y_3_2i,'ro','MarkerSize',5);
            plot(app.UIAxes7_2,x_maximo_3_2_no_mod,maximo_y_3_2_no_mod,'bo','MarkerSize',5);
            title(app.UIAxes7_2,'Thermal efficiency (\eta_{th}) - T_{t3prima} trends')
            xlabel(app.UIAxes7_2,'T_{t3} [K]')
            ylabel(app.UIAxes7_2,'\eta_{th} [-]')
            hold(app.UIAxes7_2,'off');

            [minimo_y__2, indice_minimo__2] = min(prop_eff_vec_M0_fixed_real_2);
            x_minimo__2 = Tt3_prima_val2(indice_minimo__2);
            [minimo_y__2i, indice_minimo__2i] = min(prop_eff_vec_M0_fixed_reali);
            x_minimo__2i = Tt3_prima_val2(indice_minimo__2i);
            [minimo_y__2_no_mod, indice_minimo__2_no_mod] = min(prop_eff_vec_M0_fixed_real_no_mod);
            x_minimo__2_no_mod = Tt3_prima_val2(indice_minimo__2_no_mod);
            plot(app.UIAxes10_2,Tt3_prima_val2,prop_eff_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,prop_eff_vec_M0_fixed_reali,'r--',Tt3_prima_val2,prop_eff_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes10_2,'on');
            plot(app.UIAxes10_2,x_minimo__2,minimo_y__2,'ko','MarkerSize',5);
            plot(app.UIAxes10_2,x_minimo__2i,minimo_y__2i,'ro','MarkerSize',5);
            plot(app.UIAxes10_2,x_minimo__2_no_mod,minimo_y__2_no_mod,'bo','MarkerSize',5);
            title(app.UIAxes10_2,'Propulsive efficiency (\eta_{pr}) - T_{t3prima} trends')
            xlabel(app.UIAxes10_2,'T_{t3} [K]')
            ylabel(app.UIAxes10_2,'\eta_{pr} [-]')
            hold(app.UIAxes10_2,'off');
            
            plot(app.UIAxes8_2,Tt3_prima_val2,C_ts_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,C_ts_vec_M0_fixed_reali,'r--',Tt3_prima_val2,C_ts_vec_M0_fixed_real_no_mod,'b--');
            title(app.UIAxes8_2,'CTS - T_{t3prima} trends')
            xlabel(app.UIAxes8_2,'T_{t3} [K]')
            ylabel(app.UIAxes8_2,'C_{TS} [J/Kg]')
            hold(app.UIAxes8_2);

            [minimo_y_4_2, indice_minimo_4_2] = min(W_vec_M0_fixed_real_2);
            x_minimo_4_2 = Tt3_prima_val2(indice_minimo_4_2);
            [minimo_y_4_2i, indice_minimo_4_2i] = min(W_vec_M0_fixed_reali);
            x_minimo_4_2i = Tt3_prima_val2(indice_minimo_4_2i);
            [minimo_y_4_2_no_mod, indice_minimo_4_2_no_mod] = min(W_vec_M0_fixed_real_no_mod);
            x_minimo_4_2_no_mod = Tt3_prima_val2(indice_minimo_4_2_no_mod);
            plot(app.UIAxes11_2,Tt3_prima_val2,W_vec_M0_fixed_real_2,'k-',Tt3_prima_val2,W_vec_M0_fixed_reali,'r--',Tt3_prima_val2,W_vec_M0_fixed_real_no_mod,'b--');
            hold(app.UIAxes11_2,'on');
            plot(app.UIAxes11_2,x_minimo_4_2,minimo_y_4_2,'ko','MarkerSize',5);
            plot(app.UIAxes11_2,x_minimo_4_2i,minimo_y_4_2i,'ro','MarkerSize',5);
            plot(app.UIAxes11_2,x_minimo_4_2_no_mod,minimo_y_4_2_no_mod,'bo','MarkerSize',5);
            title(app.UIAxes11_2,'Work specific output - T_{t3prima} trends')
            xlabel(app.UIAxes11_2,'T_{t3} [K]')
            ylabel(app.UIAxes11_2,'W_s [kJ]')
            hold(app.UIAxes11_2,'off');

            app.ComparisonperformedCheckBox_2.Value = true;
        end

        % Value changed function: DeltaTnotcooledDropDown_2
        function DeltaTnotcooledDropDown_2ValueChanged(app, event)
            VALUE_3 = app.DeltaTnotcooledDropDown_2.Value;
            DELTA_PRE = str2double(VALUE_3);
            app.DeltaT_preEditField.Value=DELTA_PRE/100;
        end

        % Value changed function: PressurelossesDropDown_2
        function PressurelossesDropDown_2ValueChanged(app, event)
            VALUE_4 = app.PressurelossesDropDown_2.Value;
            P_LOSS2 = str2double(VALUE_4);
            app.e_preEditField.Value=P_LOSS2/100;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            
            if strcmp(value, 'INTER')
                app.OptimizedintercoolingvaluesButton.Enable = "on";
                app.TurbojetcyclewithintercoolerButton.Enable ="on";
                app.TurbojetcyclewithprecoolingButton.Enable ="off";
                app.TurbojetcyclewithidealprecoolerButton.Enable ="off";
                app.TurbojetcyclewithrealprecoolerButton.Enable ="off";
                app.tau_cEditField.Enable="off";
                app.M_2EditField_2.Enable="off";
            elseif strcmp(value, 'PRE')
                app.TurbojetcyclewithprecoolingButton.Enable ="on";
                app.TurbojetcyclewithidealprecoolerButton.Enable ="on";
                app.TurbojetcyclewithrealprecoolerButton.Enable ="on";
                app.OptimizedintercoolingvaluesButton.Enable = "off";
                app.TurbojetcyclewithintercoolerButton.Enable ="off";
                app.tau_cEditField.Enable="on";
                app.M_2EditField_2.Enable="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Set up';
            app.M_0EditFieldLabel = uilabel(app.SetupTab);
            app.M_0EditFieldLabel.Interpreter = 'latex';
            app.M_0EditFieldLabel.Text = '$M_0$';
            app.M_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.M_0EditField.Value = 5;
            app.T_0EditFieldLabel = uilabel(app.SetupTab);
            app.T_0EditFieldLabel.Interpreter = 'latex';
            app.T_0EditFieldLabel.Text = '$T_0$';
            app.T_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.T_0EditField.Value = 216.5;
            app.DeltaT_cLabel = uilabel(app.SetupTab);
            app.DeltaT_cLabel.Interpreter = 'latex';
            app.DeltaT_cLabel.Text = '$P_{0}$';
            app.P_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.P_0EditField.Value = 22632;
            app.Label = uilabel(app.SetupTab);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '[-]';
            app.PaLabel = uilabel(app.SetupTab);
            app.PaLabel.Interpreter = 'latex';
            app.PaLabel.Text = '[Pa]';
            app.KLabel_2 = uilabel(app.SetupTab);
            app.KLabel_2.Interpreter = 'latex';
            app.KLabel_2.Text = '[K]';
            app.TurbojetcyclewithidealintercoolerButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithidealintercoolerButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithidealintercoolerButtonPushed, true);
            app.TurbojetcyclewithidealintercoolerButton.Enable = 'off';
            app.TurbojetcyclewithidealintercoolerButton.Text = 'Turbojet cycle with ideal inter-cooler';
            app.KLabel_4 = uilabel(app.SetupTab);
            app.KLabel_4.Interpreter = 'latex';
            app.KLabel_4.Text = '[K]';
            app.Label_2 = uilabel(app.SetupTab);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '[-]';
            app.Label_3 = uilabel(app.SetupTab);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '[-]';
            app.Tt_4EditField_3Label = uilabel(app.SetupTab);
            app.Tt_4EditField_3Label.Interpreter = 'latex';
            app.Tt_4EditField_3Label.Text = '$Tt_4$';
            app.Tt_4EditField_3 = uieditfield(app.SetupTab, 'numeric');
            app.Tt_4EditField_3.Value = 2100;
            app.epsilon_bEditField_2Label = uilabel(app.SetupTab);
            app.epsilon_bEditField_2Label.Interpreter = 'latex';
            app.epsilon_bEditField_2Label.Text = '$\epsilon_b$';
            app.epsilon_bEditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.epsilon_iEditFieldLabel = uilabel(app.SetupTab);
            app.epsilon_iEditFieldLabel.Interpreter = 'latex';
            app.epsilon_iEditFieldLabel.Text = '$\epsilon_i$';
            app.epsilon_iEditField = uieditfield(app.SetupTab, 'numeric');
            app.Label_4 = uilabel(app.SetupTab);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '[-]';
            app.epsilon_nEditFieldLabel = uilabel(app.SetupTab);
            app.epsilon_nEditFieldLabel.Interpreter = 'latex';
            app.epsilon_nEditFieldLabel.Text = '$\epsilon_n$';
            app.epsilon_nEditField = uieditfield(app.SetupTab, 'numeric');
            app.PressurelossesLabel = uilabel(app.SetupTab);
            app.PressurelossesLabel.Text = 'Pressure losses:';
            app.TurbojetconfigurationLabel = uilabel(app.SetupTab);
            app.TurbojetconfigurationLabel.Text = 'Turbojet configuration:';
            app.Label_10 = uilabel(app.SetupTab);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = '[-]';
            app.Label_11 = uilabel(app.SetupTab);
            app.Label_11.Interpreter = 'latex';
            app.Label_11.Text = '[-]';
            app.PolitropicefficienciesLabel = uilabel(app.SetupTab);
            app.PolitropicefficienciesLabel.Text = 'Politropic efficiencies:';
            app.eta_tpEditFieldLabel = uilabel(app.SetupTab);
            app.eta_tpEditFieldLabel.Interpreter = 'latex';
            app.eta_tpEditFieldLabel.Text = '$\eta_{tp}$';
            app.eta_tpEditField = uieditfield(app.SetupTab, 'numeric');
            app.eta_tpEditField.Value = 1;
            app.eta_cpLabel = uilabel(app.SetupTab);
            app.eta_cpLabel.Interpreter = 'latex';
            app.eta_cpLabel.Text = '$\eta_{cp}$';
            app.eta_cpEditField = uieditfield(app.SetupTab, 'numeric');
            app.eta_cpEditField.Value = 1;
            app.Label_12 = uilabel(app.SetupTab);
            app.Label_12.Interpreter = 'latex';
            app.Label_12.Text = '[-]';
            app.xEditFieldLabel = uilabel(app.SetupTab);
            app.xEditFieldLabel.Interpreter = 'latex';
            app.xEditFieldLabel.Text = '$x$';
            app.xEditField = uieditfield(app.SetupTab, 'numeric');
            app.Label_13 = uilabel(app.SetupTab);
            app.Label_13.Interpreter = 'latex';
            app.Label_13.Text = '[-]';
            app.psiLabel = uilabel(app.SetupTab);
            app.psiLabel.Interpreter = 'latex';
            app.psiLabel.Text = '$\phi$';
            app.phiEditField = uieditfield(app.SetupTab, 'numeric');
            app.phiEditField.Value = 1;
            app.SimulationperformedCheckBox = uicheckbox(app.SetupTab);
            app.SimulationperformedCheckBox.Enable = 'off';
            app.SimulationperformedCheckBox.Text = 'Simulation performed';
            app.e_interEditFieldLabel = uilabel(app.SetupTab);
            app.e_interEditFieldLabel.Interpreter = 'latex';
            app.e_interEditFieldLabel.Text = '$e_{inter}$';
            app.e_interEditField = uieditfield(app.SetupTab, 'numeric');
            app.DeltaT_interEditFieldLabel = uilabel(app.SetupTab);
            app.DeltaT_interEditFieldLabel.Interpreter = 'latex';
            app.DeltaT_interEditFieldLabel.Text = '$\Delta T_{inter}$';
            app.DeltaT_interEditField = uieditfield(app.SetupTab, 'numeric');
            app.DeltaTPreLabel = uilabel(app.SetupTab);
            app.DeltaTPreLabel.Interpreter = 'latex';
            app.DeltaTPreLabel.Text = '$\Delta T_{pre}$';
            app.DeltaT_preEditField = uieditfield(app.SetupTab, 'numeric');
            app.Realparameters = uibutton(app.SetupTab, 'push');
            app.Realparameters.ButtonPushedFcn = createCallbackFcn(app, @RealparametersPushed, true);
            app.Realparameters.Text = 'Save';
            app.SetrealcycleparametersLabel = uilabel(app.SetupTab);
            app.SetrealcycleparametersLabel.Text = 'Set real cycle parameters';
            app.Idealparameters = uibutton(app.SetupTab, 'push');
            app.Idealparameters.ButtonPushedFcn = createCallbackFcn(app, @IdealparametersButtonPushed, true);
            app.Idealparameters.Text = 'Save';
            app.SetidealcycleparametersLabel = uilabel(app.SetupTab);
            app.SetidealcycleparametersLabel.Text = 'Set ideal cycle parameters';
            app.TurbojetcyclewithprecoolingButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithprecoolingButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithprecoolingButtonPushed, true);
            app.TurbojetcyclewithprecoolingButton.Enable = 'off';
            app.TurbojetcyclewithprecoolingButton.Text = 'Turbojet cycle with pre-cooling';
            app.OptimizedintercoolingvaluesButton = uibutton(app.SetupTab, 'push');
            app.OptimizedintercoolingvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizedintercoolingvaluesButtonPushed, true);
            app.OptimizedintercoolingvaluesButton.Icon = fullfile(pathToMLAPP, 'IMAGEN.png');
            app.OptimizedintercoolingvaluesButton.Text = 'Optimized  inter-cooling values';
            app.ValuesfoundCheckBox = uicheckbox(app.SetupTab);
            app.ValuesfoundCheckBox.Enable = 'off';
            app.ValuesfoundCheckBox.Text = 'Values found';
            app.DeltaTnotcooledLabel = uilabel(app.SetupTab);
            app.DeltaTnotcooledLabel.Interpreter = 'latex';
            app.DeltaTnotcooledLabel.Text = '$\Delta$T not cooled $[\%]$';
            app.DeltaTnotcooledDropDown = uidropdown(app.SetupTab);
            app.DeltaTnotcooledDropDown.Items = {'5', '10', '15', '20', '25'};
            app.DeltaTnotcooledDropDown.ValueChangedFcn = createCallbackFcn(app, @DeltaTnotcooledDropDownValueChanged, true);
            app.DeltaTnotcooledDropDown.Value = '5';
            app.INTERCOOLERLabel = uilabel(app.SetupTab);
            app.INTERCOOLERLabel.Text = 'INTER-COOLER';
            app.PressurelossesLabel_2 = uilabel(app.SetupTab);
            app.PressurelossesLabel_2.Interpreter = 'latex';
            app.PressurelossesLabel_2.Text = 'Pressure losses $[\%]$';
            app.PressurelossesDropDown = uidropdown(app.SetupTab);
            app.PressurelossesDropDown.Items = {'5', '10', '15', '20', '25'};
            app.PressurelossesDropDown.ValueChangedFcn = createCallbackFcn(app, @PressurelossesDropDownValueChanged, true);
            app.PressurelossesDropDown.Value = '5';
            app.PRECOOLERLabel = uilabel(app.SetupTab);
            app.PRECOOLERLabel.Text = 'PRE-COOLER';
            app.IDEALINTERCOOLERLabel = uilabel(app.SetupTab);
            app.IDEALINTERCOOLERLabel.Text = 'IDEAL INTER-COOLER';
            app.REALINTERCOOLERLabel = uilabel(app.SetupTab);
            app.REALINTERCOOLERLabel.Text = 'REAL INTER-COOLER';
            app.TurbojetcyclewithrealintercoolerButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithrealintercoolerButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithrealintercoolerButtonPushed, true);
            app.TurbojetcyclewithrealintercoolerButton.Enable = 'off';
            app.TurbojetcyclewithrealintercoolerButton.Text = 'Turbojet cycle with real inter-cooler';
            app.ComparisonperformedCheckBox = uicheckbox(app.SetupTab);
            app.ComparisonperformedCheckBox.Enable = 'off';
            app.ComparisonperformedCheckBox.Text = 'Comparison performed';
            app.Label_21 = uilabel(app.SetupTab);
            app.Label_21.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_23 = uilabel(app.SetupTab);
            app.Label_23.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_25 = uilabel(app.SetupTab);
            app.Label_25.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.Label_26 = uilabel(app.SetupTab);
            app.Label_26.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _';
            app.Label_27 = uilabel(app.SetupTab);
            app.Label_27.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_28 = uilabel(app.SetupTab);
            app.Label_28.Text = ' _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _';
            app.Label_29 = uilabel(app.SetupTab);
            app.Label_29.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.SETUPLabel = uilabel(app.SetupTab);
            app.SETUPLabel.Text = 'SET-UP';
            app.CYCLELabel = uilabel(app.SetupTab);
            app.CYCLELabel.Text = 'CYCLE';
            app.IDEALLabel = uilabel(app.SetupTab);
            app.IDEALLabel.Text = 'IDEAL';
            app.REALLabel = uilabel(app.SetupTab);
            app.REALLabel.Text = 'REAL';
            app.TurbojetcyclewithintercoolerButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithintercoolerButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithintercoolerButtonPushed, true);
            app.TurbojetcyclewithintercoolerButton.Text = 'Turbojet cycle with inter-cooler';
            app.SimulationPerformedIdealCheckBox = uicheckbox(app.SetupTab);
            app.SimulationPerformedIdealCheckBox.Enable = 'off';
            app.SimulationPerformedIdealCheckBox.Text = 'Simulation Performed';
            app.SimulationPerformedIdealCheckBox_2 = uicheckbox(app.SetupTab);
            app.SimulationPerformedIdealCheckBox_2.Enable = 'off';
            app.SimulationPerformedIdealCheckBox_2.Text = 'Simulation Performed';
            app.IDEALPRECOOLERLabel = uilabel(app.SetupTab);
            app.IDEALPRECOOLERLabel.Text = 'IDEAL PRE-COOLER';
            app.REALPRECOOLERLabel = uilabel(app.SetupTab);
            app.REALPRECOOLERLabel.Text = 'REAL PRE-COOLER';
            app.Label_37 = uilabel(app.SetupTab);
            app.Label_37.Text = ' _ _ _ _ _ _ _ _ _ _ _ _ _ _   _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.Label_38 = uilabel(app.SetupTab);
            app.Label_38.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.e_preEditFieldLabel = uilabel(app.SetupTab);
            app.e_preEditFieldLabel.Interpreter = 'latex';
            app.e_preEditFieldLabel.Text = '$e_{pre}$';
            app.e_preEditField = uieditfield(app.SetupTab, 'numeric');
            app.PressurelossesLabel_3 = uilabel(app.SetupTab);
            app.PressurelossesLabel_3.Interpreter = 'latex';
            app.PressurelossesLabel_3.Text = 'Pressure losses[$\%$]';
            app.PressurelossesDropDown_2 = uidropdown(app.SetupTab);
            app.PressurelossesDropDown_2.Items = {'5', '10', '15', '20', '25'};
            app.PressurelossesDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PressurelossesDropDown_2ValueChanged, true);
            app.PressurelossesDropDown_2.Value = '5';
            app.DeltaTnotcooledDropDown_2Label = uilabel(app.SetupTab);
            app.DeltaTnotcooledDropDown_2Label.Interpreter = 'latex';
            app.DeltaTnotcooledDropDown_2Label.Text = '$\Delta$T not cooled $[\%]$';
            app.DeltaTnotcooledDropDown_2 = uidropdown(app.SetupTab);
            app.DeltaTnotcooledDropDown_2.Items = {'5', '10', '15', '20', '25'};
            app.DeltaTnotcooledDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DeltaTnotcooledDropDown_2ValueChanged, true);
            app.DeltaTnotcooledDropDown_2.Value = '5';
            app.TurbojetcyclewithrealprecoolerButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithrealprecoolerButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithrealprecoolerButtonPushed, true);
            app.TurbojetcyclewithrealprecoolerButton.Enable = 'off';
            app.TurbojetcyclewithrealprecoolerButton.Text = 'Turbojet cycle with real pre-cooler';
            app.ComparisonperformedCheckBox_2 = uicheckbox(app.SetupTab);
            app.ComparisonperformedCheckBox_2.Enable = 'off';
            app.ComparisonperformedCheckBox_2.Text = 'Comparison performed';
            app.TurbojetcyclewithidealprecoolerButton = uibutton(app.SetupTab, 'push');
            app.TurbojetcyclewithidealprecoolerButton.ButtonPushedFcn = createCallbackFcn(app, @TurbojetcyclewithidealprecoolerButtonPushed, true);
            app.TurbojetcyclewithidealprecoolerButton.Enable = 'off';
            app.TurbojetcyclewithidealprecoolerButton.Text = 'Turbojet cycle with ideal pre-cooler';
            app.SimulationperformedCheckBox_2 = uicheckbox(app.SetupTab);
            app.SimulationperformedCheckBox_2.Enable = 'off';
            app.SimulationperformedCheckBox_2.Text = 'Simulation performed';
            app.M_2EditField_2Label = uilabel(app.SetupTab);
            app.M_2EditField_2Label.Interpreter = 'latex';
            app.M_2EditField_2Label.Text = '$M_2$';
            app.M_2EditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.M_2EditField_2.Enable = 'off';
            app.M_2EditField_2.Value = 3.3;
            app.Label_39 = uilabel(app.SetupTab);
            app.Label_39.Interpreter = 'latex';
            app.Label_39.Text = '$\tau_c$';
            app.tau_cEditField = uieditfield(app.SetupTab, 'numeric');
            app.tau_cEditField.Enable = 'off';
            app.tau_cEditField.Value = 2;
            app.Switch_2 = uiswitch(app.SetupTab, 'toggle');
            app.Switch_2.Items = {'INTER', 'PRE'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'INTER';
            app.INTERCOOLINGTab = uitab(app.TabGroup);
            app.INTERCOOLINGTab.Title = 'INTER-COOLING';
            app.Image3 = uiimage(app.INTERCOOLINGTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Engine-of-turbofan-with-intercooler.png');
            app.IdealTt25analysisTab = uitab(app.TabGroup);
            app.IdealTt25analysisTab.Title = 'Ideal Tt 2.5'' analysis';
            app.UIAxes12 = uiaxes(app.IdealTt25analysisTab);
            title(app.UIAxes12, 'Title')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes13 = uiaxes(app.IdealTt25analysisTab);
            title(app.UIAxes13, 'Title')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.OptimizedvaluesinterTab = uitab(app.TabGroup);
            app.OptimizedvaluesinterTab.Title = 'Optimized values inter';
            app.T_t3Label_2 = uilabel(app.OptimizedvaluesinterTab);
            app.T_t3Label_2.Interpreter = 'latex';
            app.T_t3Label_2.Text = '$T_{t3''}$';
            app.EditField_26 = uieditfield(app.OptimizedvaluesinterTab, 'numeric');
            app.EditField_27 = uieditfield(app.OptimizedvaluesinterTab, 'numeric');
            app.EditField_32 = uieditfield(app.OptimizedvaluesinterTab, 'numeric');
            app.EditField_33 = uieditfield(app.OptimizedvaluesinterTab, 'numeric');
            app.T_t25Label_2 = uilabel(app.OptimizedvaluesinterTab);
            app.T_t25Label_2.Interpreter = 'latex';
            app.T_t25Label_2.Text = '$T_{t2.5}$';
            app.EditField_26Label = uilabel(app.OptimizedvaluesinterTab);
            app.EditField_26Label.Text = '1st iteration';
            app.EditField_32Label = uilabel(app.OptimizedvaluesinterTab);
            app.EditField_32Label.Text = '6th iteration';
            app.Image = uiimage(app.OptimizedvaluesinterTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'FLECHA.png');
            app.Image_2 = uiimage(app.OptimizedvaluesinterTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'FLECHA.png');
            app.DesignpointTab = uitab(app.TabGroup);
            app.DesignpointTab.Title = 'Design point';
            app.PerformancesLabel = uilabel(app.DesignpointTab);
            app.PerformancesLabel.Text = 'Performances:';
            app.psimsLabel = uilabel(app.DesignpointTab);
            app.psimsLabel.Interpreter = 'latex';
            app.psimsLabel.Text = '$\psi$  [m/s]';
            app.C_TSkJgLabel = uilabel(app.DesignpointTab);
            app.C_TSkJgLabel.Interpreter = 'latex';
            app.C_TSkJgLabel.Text = '$C_{TS}$  [kJ/g]';
            app.DeltaK_ckJLabel = uilabel(app.DesignpointTab);
            app.DeltaK_ckJLabel.Interpreter = 'latex';
            app.DeltaK_ckJLabel.Text = '$\Delta K_{c}$  [kJ]';
            app.OverallLabel = uilabel(app.DesignpointTab);
            app.OverallLabel.Interpreter = 'latex';
            app.OverallLabel.Text = 'Overall';
            app.PropulsiveLabel = uilabel(app.DesignpointTab);
            app.PropulsiveLabel.Interpreter = 'latex';
            app.PropulsiveLabel.Text = 'Propulsive';
            app.ThermalLabel = uilabel(app.DesignpointTab);
            app.ThermalLabel.Interpreter = 'latex';
            app.ThermalLabel.Text = 'Thermal';
            app.Label_14 = uilabel(app.DesignpointTab);
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.DesignpointTab);
            app.Label_15.Text = '0-2';
            app.Label_16 = uilabel(app.DesignpointTab);
            app.Label_16.Text = '2-3';
            app.Label_17 = uilabel(app.DesignpointTab);
            app.Label_17.Text = '4-5';
            app.Label_18 = uilabel(app.DesignpointTab);
            app.Label_18.Text = '5-9';
            app.Label_19 = uilabel(app.DesignpointTab);
            app.Label_19.Text = '3-4';
            app.T_t0Label = uilabel(app.DesignpointTab);
            app.T_t0Label.Interpreter = 'latex';
            app.T_t0Label.Text = '$T_{t0}$';
            app.P_t0Label = uilabel(app.DesignpointTab);
            app.P_t0Label.Interpreter = 'latex';
            app.P_t0Label.Text = '$P_{t0}$';
            app.pi_fsLabel = uilabel(app.DesignpointTab);
            app.pi_fsLabel.Interpreter = 'latex';
            app.pi_fsLabel.Text = '$\pi_{fs}$';
            app.T_t2Label = uilabel(app.DesignpointTab);
            app.T_t2Label.Interpreter = 'latex';
            app.T_t2Label.Text = '$T_{t2}$';
            app.P_t2Label = uilabel(app.DesignpointTab);
            app.P_t2Label.Interpreter = 'latex';
            app.P_t2Label.Text = '$P_{t2}$';
            app.T_t3Label = uilabel(app.DesignpointTab);
            app.T_t3Label.Interpreter = 'latex';
            app.T_t3Label.Text = '$T_{t3}$';
            app.P_t3Label = uilabel(app.DesignpointTab);
            app.P_t3Label.Interpreter = 'latex';
            app.P_t3Label.Text = '$P_{t3}$';
            app.alpha_bLabel = uilabel(app.DesignpointTab);
            app.alpha_bLabel.Interpreter = 'latex';
            app.alpha_bLabel.Text = '$\alpha_{b}$';
            app.P_t4Label = uilabel(app.DesignpointTab);
            app.P_t4Label.Interpreter = 'latex';
            app.P_t4Label.Text = '$P_{t4}$';
            app.pi_cLabel = uilabel(app.DesignpointTab);
            app.pi_cLabel.Interpreter = 'latex';
            app.pi_cLabel.Text = '$\pi_{c}$';
            app.T_t5Label = uilabel(app.DesignpointTab);
            app.T_t5Label.Interpreter = 'latex';
            app.T_t5Label.Text = '$T_{t5}$';
            app.P_t5Label = uilabel(app.DesignpointTab);
            app.P_t5Label.Interpreter = 'latex';
            app.P_t5Label.Text = '$P_{t5}$';
            app.alphaLabel = uilabel(app.DesignpointTab);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha ''$';
            app.T_9Label = uilabel(app.DesignpointTab);
            app.T_9Label.Interpreter = 'latex';
            app.T_9Label.Text = '$T_{9}$';
            app.P_t9Label = uilabel(app.DesignpointTab);
            app.P_t9Label.Interpreter = 'latex';
            app.P_t9Label.Text = '$P_{t9}$';
            app.pi_9Label = uilabel(app.DesignpointTab);
            app.pi_9Label.Interpreter = 'latex';
            app.pi_9Label.Text = '$\pi_{9}$';
            app.EditField = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_13 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_17.Editable = 'off';
            app.V_9Label = uilabel(app.DesignpointTab);
            app.V_9Label.Interpreter = 'latex';
            app.V_9Label.Text = '$V_{9}$';
            app.EditField_18 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_20.Editable = 'off';
            app.EditField_21 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_21.Editable = 'off';
            app.EditField_22 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_22.Editable = 'off';
            app.EditField_23 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_23.Editable = 'off';
            app.EfficienciesLabel = uilabel(app.DesignpointTab);
            app.EfficienciesLabel.Text = 'Efficiencies:';
            app.KLabel_8 = uilabel(app.DesignpointTab);
            app.KLabel_8.Interpreter = 'latex';
            app.KLabel_8.Text = '[K]';
            app.kPaLabel = uilabel(app.DesignpointTab);
            app.kPaLabel.Interpreter = 'latex';
            app.kPaLabel.Text = '[kPa]';
            app.Label_20 = uilabel(app.DesignpointTab);
            app.Label_20.Interpreter = 'latex';
            app.Label_20.Text = '[-]';
            app.T_t4Label = uilabel(app.DesignpointTab);
            app.T_t4Label.Interpreter = 'latex';
            app.T_t4Label.Text = '$T_{t4}$';
            app.EditField_24 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_24.Editable = 'off';
            app.EditField_25 = uieditfield(app.DesignpointTab, 'numeric');
            app.EditField_25.Editable = 'off';
            app.V_0Label = uilabel(app.DesignpointTab);
            app.V_0Label.Interpreter = 'latex';
            app.V_0Label.Text = '$V_{0}$';
            app.msLabel = uilabel(app.DesignpointTab);
            app.msLabel.Interpreter = 'latex';
            app.msLabel.Text = '[m/s]';
            app.INTERCOOLINGPARAMETERSLabel = uilabel(app.DesignpointTab);
            app.INTERCOOLINGPARAMETERSLabel.Text = 'INTERCOOLING PARAMETERS';
            app.T_t25EditFieldLabel = uilabel(app.DesignpointTab);
            app.T_t25EditFieldLabel.Interpreter = 'latex';
            app.T_t25EditFieldLabel.Text = '$T_{t2.5}$';
            app.T_t25EditField = uieditfield(app.DesignpointTab, 'numeric');
            app.T_t25Label = uilabel(app.DesignpointTab);
            app.T_t25Label.Interpreter = 'latex';
            app.T_t25Label.Text = '$T_{t2.5''}$';
            app.T_t25EditField_2 = uieditfield(app.DesignpointTab, 'numeric');
            app.P_t25EditFieldLabel = uilabel(app.DesignpointTab);
            app.P_t25EditFieldLabel.Interpreter = 'latex';
            app.P_t25EditFieldLabel.Text = '$P_{t2.5}$';
            app.P_t25EditField = uieditfield(app.DesignpointTab, 'numeric');
            app.P_t25Label = uilabel(app.DesignpointTab);
            app.P_t25Label.Interpreter = 'latex';
            app.P_t25Label.Text = '$P_{t2.5''}$';
            app.P_t25EditField_2 = uieditfield(app.DesignpointTab, 'numeric');
            app.W_skJEditFieldLabel = uilabel(app.DesignpointTab);
            app.W_skJEditFieldLabel.Interpreter = 'latex';
            app.W_skJEditFieldLabel.Text = '$W_s$  [kJ]';
            app.W_skJEditField = uieditfield(app.DesignpointTab, 'numeric');
            app.TrendsTab = uitab(app.TabGroup);
            app.TrendsTab.Title = 'Trends';
            app.UIAxes = uiaxes(app.TrendsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.TrendsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.TrendsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.TrendsTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2_2 = uiaxes(app.TrendsTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes5 = uiaxes(app.TrendsTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.WsanalysisTab = uitab(app.TabGroup);
            app.WsanalysisTab.Title = 'Ws analysis';
            app.UIAxes14 = uiaxes(app.WsanalysisTab);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.W_sLabel = uilabel(app.WsanalysisTab);
            app.W_sLabel.Interpreter = 'latex';
            app.W_sLabel.Text = '$W_s$';
            app.LosseseffectTab = uitab(app.TabGroup);
            app.LosseseffectTab.Title = 'Losses effect';
            app.UIAxes6 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes8, 'Title')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes9, 'Title')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.UIAxes10 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes10, 'Title')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.LosseseffectTab);
            title(app.UIAxes11, 'Title')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.PRECOOLINGTab = uitab(app.TabGroup);
            app.PRECOOLINGTab.Title = 'PRE-COOLING';
            app.Image2 = uiimage(app.PRECOOLINGTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'htx-test-article-close-up-4web.jpg');
            app.DesignpointTab_2 = uitab(app.TabGroup);
            app.DesignpointTab_2.Title = 'Design point';
            app.PerformancesLabel_2 = uilabel(app.DesignpointTab_2);
            app.PerformancesLabel_2.Text = 'Performances:';
            app.psimsLabel_2 = uilabel(app.DesignpointTab_2);
            app.psimsLabel_2.Interpreter = 'latex';
            app.psimsLabel_2.Text = '$\psi$  [m/s]';
            app.C_TSkJgLabel_2 = uilabel(app.DesignpointTab_2);
            app.C_TSkJgLabel_2.Interpreter = 'latex';
            app.C_TSkJgLabel_2.Text = '$C_{TS}$  [kJ/g]';
            app.DeltaK_ckJLabel_2 = uilabel(app.DesignpointTab_2);
            app.DeltaK_ckJLabel_2.Interpreter = 'latex';
            app.DeltaK_ckJLabel_2.Text = '$\Delta K_{c}$  [kJ]';
            app.OverallLabel_2 = uilabel(app.DesignpointTab_2);
            app.OverallLabel_2.Interpreter = 'latex';
            app.OverallLabel_2.Text = 'Overall';
            app.PropulsiveLabel_2 = uilabel(app.DesignpointTab_2);
            app.PropulsiveLabel_2.Interpreter = 'latex';
            app.PropulsiveLabel_2.Text = 'Propulsive';
            app.ThermalLabel_2 = uilabel(app.DesignpointTab_2);
            app.ThermalLabel_2.Interpreter = 'latex';
            app.ThermalLabel_2.Text = 'Thermal';
            app.Label_30 = uilabel(app.DesignpointTab_2);
            app.Label_30.Text = '0';
            app.Label_31 = uilabel(app.DesignpointTab_2);
            app.Label_31.Text = '0-2';
            app.Label_32 = uilabel(app.DesignpointTab_2);
            app.Label_32.Text = '2-3';
            app.Label_33 = uilabel(app.DesignpointTab_2);
            app.Label_33.Text = '4-5';
            app.Label_34 = uilabel(app.DesignpointTab_2);
            app.Label_34.Text = '5-9';
            app.Label_35 = uilabel(app.DesignpointTab_2);
            app.Label_35.Text = '3-4';
            app.T_t0Label_2 = uilabel(app.DesignpointTab_2);
            app.T_t0Label_2.Interpreter = 'latex';
            app.T_t0Label_2.Text = '$T_{t0}$';
            app.P_t0Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t0Label_2.Interpreter = 'latex';
            app.P_t0Label_2.Text = '$P_{t0}$';
            app.pi_fsLabel_2 = uilabel(app.DesignpointTab_2);
            app.pi_fsLabel_2.Interpreter = 'latex';
            app.pi_fsLabel_2.Text = '$\pi_{fs}$';
            app.T_t2Label_2 = uilabel(app.DesignpointTab_2);
            app.T_t2Label_2.Interpreter = 'latex';
            app.T_t2Label_2.Text = '$T_{t2}$';
            app.P_t2Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t2Label_2.Interpreter = 'latex';
            app.P_t2Label_2.Text = '$P_{t2}$';
            app.T_t3Label_3 = uilabel(app.DesignpointTab_2);
            app.T_t3Label_3.Interpreter = 'latex';
            app.T_t3Label_3.Text = '$T_{t3}$';
            app.P_t3Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t3Label_2.Interpreter = 'latex';
            app.P_t3Label_2.Text = '$P_{t3}$';
            app.alpha_bLabel_2 = uilabel(app.DesignpointTab_2);
            app.alpha_bLabel_2.Interpreter = 'latex';
            app.alpha_bLabel_2.Text = '$\alpha_{b}$';
            app.P_t4Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t4Label_2.Interpreter = 'latex';
            app.P_t4Label_2.Text = '$P_{t4}$';
            app.pi_cLabel_2 = uilabel(app.DesignpointTab_2);
            app.pi_cLabel_2.Interpreter = 'latex';
            app.pi_cLabel_2.Text = '$\pi_{c}$';
            app.T_t5Label_2 = uilabel(app.DesignpointTab_2);
            app.T_t5Label_2.Interpreter = 'latex';
            app.T_t5Label_2.Text = '$T_{t5}$';
            app.P_t5Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t5Label_2.Interpreter = 'latex';
            app.P_t5Label_2.Text = '$P_{t5}$';
            app.alphaLabel_2 = uilabel(app.DesignpointTab_2);
            app.alphaLabel_2.Interpreter = 'latex';
            app.alphaLabel_2.Text = '$\alpha ''$';
            app.T_9Label_2 = uilabel(app.DesignpointTab_2);
            app.T_9Label_2.Interpreter = 'latex';
            app.T_9Label_2.Text = '$T_{9}$';
            app.P_t9Label_2 = uilabel(app.DesignpointTab_2);
            app.P_t9Label_2.Interpreter = 'latex';
            app.P_t9Label_2.Text = '$P_{t9}$';
            app.pi_9Label_2 = uilabel(app.DesignpointTab_2);
            app.pi_9Label_2.Interpreter = 'latex';
            app.pi_9Label_2.Text = '$\pi_{9}$';
            app.EditField_34 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_34.Editable = 'off';
            app.EditField_35 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_35.Editable = 'off';
            app.EditField_36 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_36.Editable = 'off';
            app.EditField_37 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_37.Editable = 'off';
            app.EditField_38 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_38.Editable = 'off';
            app.EditField_39 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_39.Editable = 'off';
            app.EditField_40 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_40.Editable = 'off';
            app.EditField_41 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_41.Editable = 'off';
            app.EditField_42 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_42.Editable = 'off';
            app.EditField_43 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_43.Editable = 'off';
            app.EditField_44 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_44.Editable = 'off';
            app.EditField_45 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_45.Editable = 'off';
            app.EditField_46 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_46.Editable = 'off';
            app.EditField_47 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_47.Editable = 'off';
            app.EditField_48 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_48.Editable = 'off';
            app.EditField_49 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_49.Editable = 'off';
            app.EditField_50 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_50.Editable = 'off';
            app.V_9Label_2 = uilabel(app.DesignpointTab_2);
            app.V_9Label_2.Interpreter = 'latex';
            app.V_9Label_2.Text = '$V_{9}$';
            app.EditField_51 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_51.Editable = 'off';
            app.EditField_52 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_52.Editable = 'off';
            app.EditField_53 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_53.Editable = 'off';
            app.EditField_54 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_54.Editable = 'off';
            app.EditField_55 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_55.Editable = 'off';
            app.EditField_56 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_56.Editable = 'off';
            app.EfficienciesLabel_2 = uilabel(app.DesignpointTab_2);
            app.EfficienciesLabel_2.Text = 'Efficiencies:';
            app.KLabel_9 = uilabel(app.DesignpointTab_2);
            app.KLabel_9.Interpreter = 'latex';
            app.KLabel_9.Text = '[K]';
            app.kPaLabel_2 = uilabel(app.DesignpointTab_2);
            app.kPaLabel_2.Interpreter = 'latex';
            app.kPaLabel_2.Text = '[kPa]';
            app.Label_36 = uilabel(app.DesignpointTab_2);
            app.Label_36.Interpreter = 'latex';
            app.Label_36.Text = '[-]';
            app.T_t4Label_2 = uilabel(app.DesignpointTab_2);
            app.T_t4Label_2.Interpreter = 'latex';
            app.T_t4Label_2.Text = '$T_{t4}$';
            app.EditField_57 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_57.Editable = 'off';
            app.EditField_58 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.EditField_58.Editable = 'off';
            app.V_0Label_2 = uilabel(app.DesignpointTab_2);
            app.V_0Label_2.Interpreter = 'latex';
            app.V_0Label_2.Text = '$V_{0}$';
            app.msLabel_2 = uilabel(app.DesignpointTab_2);
            app.msLabel_2.Interpreter = 'latex';
            app.msLabel_2.Text = '[m/s]';
            app.PRECOOLINGPARAMETERLabel = uilabel(app.DesignpointTab_2);
            app.PRECOOLINGPARAMETERLabel.Text = 'PRE-COOLING PARAMETER';
            app.WkJEditField_2Label = uilabel(app.DesignpointTab_2);
            app.WkJEditField_2Label.Interpreter = 'latex';
            app.WkJEditField_2Label.Text = '$W$  [kJ]';
            app.WkJEditField_2 = uieditfield(app.DesignpointTab_2, 'numeric');
            app.M_2EditFieldLabel = uilabel(app.DesignpointTab_2);
            app.M_2EditFieldLabel.Interpreter = 'latex';
            app.M_2EditFieldLabel.Text = '$M_2$';
            app.M_2EditField = uieditfield(app.DesignpointTab_2, 'numeric');
            app.TrendsT_t3Tab = uitab(app.TabGroup);
            app.TrendsT_t3Tab.Title = 'Trends T_t3''';
            app.UIAxes_2 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_3 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes2_3, 'Title')
            xlabel(app.UIAxes2_3, 'X')
            ylabel(app.UIAxes2_3, 'Y')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes3_2 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes3_2, 'Title')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes4_2 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes4_2, 'Title')
            xlabel(app.UIAxes4_2, 'X')
            ylabel(app.UIAxes4_2, 'Y')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes2_4 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes2_4, 'Title')
            xlabel(app.UIAxes2_4, 'X')
            ylabel(app.UIAxes2_4, 'Y')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes5_2 = uiaxes(app.TrendsT_t3Tab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.TrendsM_2Tab = uitab(app.TabGroup);
            app.TrendsM_2Tab.Title = 'Trends M_2';
            app.UIAxes_3 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2_5 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes2_5, 'Title')
            xlabel(app.UIAxes2_5, 'X')
            ylabel(app.UIAxes2_5, 'Y')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes3_3 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes3_3, 'Title')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes4_3 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes4_3, 'Title')
            xlabel(app.UIAxes4_3, 'X')
            ylabel(app.UIAxes4_3, 'Y')
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes2_6 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes2_6, 'Title')
            xlabel(app.UIAxes2_6, 'X')
            ylabel(app.UIAxes2_6, 'Y')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes5_3 = uiaxes(app.TrendsM_2Tab);
            title(app.UIAxes5_3, 'Title')
            xlabel(app.UIAxes5_3, 'X')
            ylabel(app.UIAxes5_3, 'Y')
            zlabel(app.UIAxes5_3, 'Z')
            app.LosseseffectTab_2 = uitab(app.TabGroup);
            app.LosseseffectTab_2.Title = 'Losses effect';
            app.UIAxes6_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            zlabel(app.UIAxes6_2, 'Z')
            app.UIAxes7_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.UIAxes8_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes8_2, 'Title')
            xlabel(app.UIAxes8_2, 'X')
            ylabel(app.UIAxes8_2, 'Y')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes9_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes9_2, 'Title')
            xlabel(app.UIAxes9_2, 'X')
            ylabel(app.UIAxes9_2, 'Y')
            zlabel(app.UIAxes9_2, 'Z')
            app.UIAxes10_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes10_2, 'Title')
            xlabel(app.UIAxes10_2, 'X')
            ylabel(app.UIAxes10_2, 'Y')
            zlabel(app.UIAxes10_2, 'Z')
            app.UIAxes11_2 = uiaxes(app.LosseseffectTab_2);
            title(app.UIAxes11_2, 'Title')
            xlabel(app.UIAxes11_2, 'X')
            ylabel(app.UIAxes11_2, 'Y')
            zlabel(app.UIAxes11_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIMULATOR_TURBOJET_TT3P

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end